classdef gisapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HomeMenu               matlab.ui.container.Menu
        NewprojectMenu         matlab.ui.container.Menu
        OpenprojectMenu        matlab.ui.container.Menu
        SaveprojectMenu        matlab.ui.container.Menu
        AddprofileMenu         matlab.ui.container.Menu
        DeleteprofileMenu      matlab.ui.container.Menu
        MapMenu                matlab.ui.container.Menu
        LoadbasemapMenu        matlab.ui.container.Menu
        StreetsLightMenu       matlab.ui.container.Menu
        StreetsDarkMenu        matlab.ui.container.Menu
        StreetsMenu            matlab.ui.container.Menu
        SatelliteMenu          matlab.ui.container.Menu
        TopographicMenu        matlab.ui.container.Menu
        ColorterrainMenu       matlab.ui.container.Menu
        GreyterrainMenu        matlab.ui.container.Menu
        LandcoverMenu          matlab.ui.container.Menu
        BluegreenMenu          matlab.ui.container.Menu
        GreylandMenu           matlab.ui.container.Menu
        DarkwaterMenu          matlab.ui.container.Menu
        NoneMenu               matlab.ui.container.Menu
        Menu_2                 matlab.ui.container.Menu
        ProcessMenu            matlab.ui.container.Menu
        ProcessAsMenu          matlab.ui.container.Menu
        Toolbar                matlab.ui.container.Toolbar
        ColorButton            matlab.ui.container.toolbar.PushTool
        ZoomOnSelectedProfile  matlab.ui.container.toolbar.PushTool
        ZoomOnEverithing       matlab.ui.container.toolbar.PushTool
        Panel_3                matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        TabGroup               matlab.ui.container.TabGroup
        LayersTab              matlab.ui.container.Tab
        RemoveButton           matlab.ui.control.Button
        AddButton              matlab.ui.control.Button
        DownButton             matlab.ui.control.Button
        UpButton               matlab.ui.control.Button
        Tree                   matlab.ui.container.CheckBoxTree
        InfoPanel              matlab.ui.container.Panel
    end


    properties (Access = private)
        processingapp
        % Map properties
        basemap='none'
        gx
        latEle
        lonEle
        latlim
        lonlim
        zona
        PlotElectrodes
        ShownPositions
        profilePath='none' 
        profileName='none' 
        profileColor='[1 1 0]' 
        PROFILES
        selectedNodes
        TAB
        MPOL
        RAWDATA
        DIST
        QuiFile
    end

    methods (Access = public)

        function importdata_processingapp(app,Q,TAB)
            %% IMPORT DATA FROM THE PROCESSING APP (in round
            % brackets input from processing app)
            % Q contains the coordinates of the selected electrodes
            % Showpositions contains the plot of electrodes selected in
            % processingapp and overlaps the profile plot. Each time there
            % is a new selection the current Showpositions is deleted and a
            % new is created from Q


            ShownPositions=app.ShownPositions;
            if ~isempty(ShownPositions)
                delete (ShownPositions);
            end
            ShownPositions=gobjects(0);


            [lon, lat] = app.GeoPlotEle(TAB{4}(Q{1},3),TAB{4}(Q{1},4));
            ShownPositions=[ShownPositions geoscatter(app.gx,lat,lon,'o','MarkerFaceColor','#bb00bb','MarkerEdgeColor',[1 1 1], 'LineWidth', 1)];

            [lon, lat] = app.GeoPlotEle(TAB{4}(Q{2},3),TAB{4}(Q{2},4));
            ShownPositions=[ShownPositions geoscatter(app.gx,lat,lon,'o','MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 1 1], 'LineWidth', 1)];

            [lon, lat] = app.GeoPlotEle(TAB{4}(Q{3},3),TAB{4}(Q{3},4));
            ShownPositions=[ShownPositions geoscatter(app.gx,lat,lon,'o','MarkerFaceColor',[0 0 1],'MarkerEdgeColor',[1 1 1], 'LineWidth', 1)];


            app.ShownPositions=ShownPositions;
        end
    end

    methods (Access = private)

        function AddProfile(app)
            % LOAD PROFILE TO THE TREE AND THE PLOT
            [filenameQui,pathnameQui]=uigetfile('*.qu*');
            if filenameQui ~= 0
                addpath(pathnameQui);
                app.profilePath=pathnameQui;
                app.profileName=filenameQui;
                app.profileColor='[1 1 0]';

                fileID=fopen([app.profilePath app.profileName]);
                filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                %% Add to PROFILES
                PROFILES=app.PROFILES;
                newrow=height(PROFILES)+1;
                PROFILES(newrow,1)=app.profileName;
                PROFILES(newrow,2)=app.profileColor;
                PROFILES(newrow,3)=app.profilePath;

                % sections from .qui file
                % TAB{1}-TAB{10}
                ROW=zeros(1,11);
                i=0;
                for i=1:11
                    row_i=strfind(filescan{1}, append('%',num2str(i)));
                    row_i=find(~cellfun('isempty',row_i),1);

                    ROW(i)=row_i;
                end               % ROW (vector) contains the number of the row of each section

                j=0;
                for j=1:10
                    TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                end                % TAB (cell array) contains in each cell each section

                TAB{1,1}=split(char(TAB{1,1}(1)));
                utmZone=string(TAB{1,1}(2));
                app.zona=append(utmZone{1}(1:2),' ',utmZone{1}(3));

                TAB{1,3}=str2num(char(TAB{1,3}));
                TAB{1,4}=str2num(char(TAB{1,4}));

                TAB{1,6}=str2num(char(TAB{1,6}));
                TAB{1,7}=str2num(char(TAB{1,7}));
                TAB{1,8}=str2num(char(TAB{1,8}));


                %% Create Node in Tree
                orderTree=app.Tree.Children;
                orderChecked=app.Tree.CheckedNodes;
                nodeProfile=uitreenode(app.Tree,'Text',app.profileName,'Tag',[app.profilePath app.profileName]);
                app.Tree.Children=[nodeProfile; orderTree];
                app.Tree.CheckedNodes=[nodeProfile; orderChecked];


                %% Geographic plot Electrodes

                [lon, lat] = app.GeoPlotEle(TAB{4}(:,3),TAB{4}(:,4));
                hold(app.gx, 'on')
                %
                PlotElectrodes(height(app.Tree.CheckedNodes),1) = geoscatter(app.gx,lat,lon,'o','MarkerFaceColor',app.profileColor,'MarkerEdgeColor',[0 0 0],'Tag',[app.profilePath app.profileName]);
                app.PlotElectrodes = PlotElectrodes;
                app.PROFILES=PROFILES;

            end
        end

        function DeleteProfile(app)
            % DELETE PROFILE FROM THE TREE AND THE PLOT


            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(app.Tree.SelectedNodes)
                if ismember(selectedNodes,checkedNodes)==1
                    for i=1:height(app.gx.Children)
                        if strcmp(app.gx.Children(i).Tag,app.Tree.SelectedNodes.Tag)==1
                            j=i;
                        end
                    end
                    delete(app.gx.Children(j));
                end


                PROFILES=app.PROFILES;
                d=find(append(PROFILES(:,3),PROFILES(:,1))==app.Tree.SelectedNodes.Tag);
                PROFILES(d,:)=[];
                delete(app.Tree.SelectedNodes);

                app.PROFILES=PROFILES;

            end

            if ~isempty(app.gx.Children)
                MaxLon = [];
                MinLon = [];
                MaxLat = [];
                MinLat = [];
                path = append(app.PROFILES(:,3), app.PROFILES(:,1));

                for k=2:height(path)
                    fileID=fopen(path(k));
                    filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');
                    ROW=zeros(1,11);
                    i=0;
                    for i=1:11
                        row_i=strfind(filescan{1}, append('%',num2str(i)));
                        row_i=find(~cellfun('isempty',row_i),1);

                        ROW(i)=row_i;
                    end               % ROW (vector) contains the number of the row of each section

                    j=0;
                    for j=1:10
                        TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                    end                % TAB (cell array) contains in each cell each section

                    TAB{1,1}=split(char(TAB{1,1}(1)));
                    utmZone=string(TAB{1,1}(2));
                    app.zona=append(utmZone{1}(1:2),' ',utmZone{1}(3));

                    TAB{1,3}=str2num(char(TAB{1,3}));
                    TAB{1,4}=str2num(char(TAB{1,4}));

                    [lon, lat] = app.GeoPlotEle(TAB{4}(:,3),TAB{4}(:,4));
                    MaxLon = max([MaxLon, max(lon)]);
                    MinLon = min([MinLon, min(lon)]);
                    MaxLat = max([MaxLat, max(lat)]);
                    MinLat = min([MinLat, min(lat)]);
                end
                app.latEle = [MinLat MaxLat];
                app.lonEle = [MinLon MaxLon];
                app.latlim = app.latEle;
                app.lonlim = app.lonEle;
            else
                app.latlim=[35 48];
                app.lonlim=[6 19];
                app.latEle=[91 -91];
                app.lonEle=[181 -181];

            end

            app.LoadBasemap;

        end

        function ClearAll(app)
            %CLEAR ALL GIS WINDOW TO START A NEW PROJECT
            cla(app.gx);
            delete(app.Tree.Children);

            app.mapPathName='none';
            app.profilePath='none';
            app.profileName='none';
            app.PROFILES=["FILENAME.QUI" "[R G B]" "PATHNAME"];
        end

        function LoadBasemap(app)
            % Loads a Basemap in WGS84 coordinates and
            % zooms on the subject: Italy at the start, the plotted
            % electrodes after
            if isempty(app.gx)
                app.gx = geoaxes(app.Panel_3);
                app.gx.FontSize = 9;
            end
            geobasemap(app.gx, app.basemap);
            geolimits(app.gx, app.latlim, app.lonlim); %zoom

        end

        function [lon, lat] = GeoPlotEle(app, xs, ys)
            % Converts UTM to WGS84 and calculates new map limits
            lat=zeros(size(xs));
            lon=zeros(size(xs));
            for i=1:length(xs)
                [lat(i), lon(i)] = utm2deg(xs(i),ys(i),app.zona);
            end

            % Adapt the extension of the map to the position of electrodes
            if max(lat)> app.latEle(2)
                app.latEle(2)=max(lat);
            end
            if min(lat)< app.latEle(1)
                app.latEle(1)=min(lat);
            end

            if max(lon)> app.lonEle(2)
                app.lonEle(2)=max(lon);
            end

            if min(lon)< app.lonEle(1)
                app.lonEle(1)=min(lon);
            end

            app.latlim=app.latEle;
            app.lonlim=app.lonEle;
            app.LoadBasemap;
        end


        function ReadQuiFile(app)
            % ACCESS TO THE PROCESSING WINDOW OF THE SELECTED PROFILE
            % Open the qui file associated, scan it, create the variables to pass
            % to the processing widow:
            %   - TAB (cell array) contains in each cell each section from 1 to 10 of qui file
            %   - MPOL (array) contains the section of the multipoles (11th)
            %   - RAWDATA (array) rawdata for each multipole
            %   - DIST (array) for each electrode, number and its relative
            %   distance in the profile
            %   - QuiFile (string) path to the qui file


            if isempty(app.Tree.SelectedNodes)

                err=uiconfirm(app.UIFigure,'Please select a profile.', 'Processing request','Icon','warning','Options',{'OK'});

            else
                app.TAB=[];
                app.MPOL=[];
                app.RAWDATA=[];
                app.DIST=[];

                fileID=fopen(app.Tree.SelectedNodes.Tag);
                filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                %% sections from .qui file
                %TAB{1}-TAB{10}
                ROW=zeros(1,11);
                i=0;
                for i=1:11
                    row_i=strfind(filescan{1}, append('%',num2str(i)));
                    row_i=find(~cellfun('isempty',row_i),1);

                    ROW(i)=row_i;
                end          % ROW (vector) contains the number of the row of each section

                j=0;
                for j=1:10
                    TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-2);
                end          % TAB (cell array) contains in each cell each section

                %                 TAB{1,1}=str2num(char(TAB{1,1}));
                TAB{1,2}=str2num(char(TAB{1,2}));
                TAB{1,3}=str2num(char(TAB{1,3}));

                TAB{1,4}=str2num(char(TAB{1,4}));

                %TAB{1,5}

                %TAB{1,6}

                TAB{1,7}=str2num(char(TAB{1,7}));

                %TAB{1,8}

                %TAB{1,9}

                TAB{1,10}=split(char(TAB{1,10}))';

                %TAB{11}:MPOL
                rows_filescan=height(filescan{1});  %Computes number of rows in filescan

                cols_MPOL=0;
                rows_MPOL=0;
                for m=ROW(1,11)+1:rows_filescan
                    [A,COUNT,ERRMSG,NEXTINDEX] = sscanf(filescan{1}{m,1},'%e');
                    if COUNT>0
                        cols_MPOL=max(cols_MPOL,COUNT);
                        rows_MPOL=rows_MPOL+1;
                    end

                end     % Counts Col and Rows that will be in MPOL

                MPOL=zeros(rows_MPOL,cols_MPOL);

                k=0;
                for m=ROW(1,11)+1:rows_filescan
                    [A,COUNT,ERRMSG,NEXTINDEX] = sscanf(filescan{1}{m,1},'%e');
                    if COUNT>0
                        k=k+1;
                        MPOL(k,:)=A';
                    end

                    if isempty(ERRMSG)
                        RAWDATA{k,1}="";
                    else
                        RAWDATA{k,1}=filescan{1}{m,1}(NEXTINDEX:end);
                    end

                end                     % Create MPOL matrix and rawdata cell array

                DIST=zeros(height(TAB{4}),2);   % In DIST col 1 is the elNumber, col 2 is the distance
                DIST(1,1)=1;
                DIST(1,2)=0;

                %                 DIST(2,1)=2;
                %                 DIST(2,2)=sqrt((TAB{4}(2,3)-TAB{4}(1,3))^2+(TAB{4}(2,4)-TAB{4}(1,4))^2);

                for m=2:height(TAB{4})
                    DIST(m,2)=sqrt((TAB{4}(m,3)-TAB{4}(m-1,3)).^2+(TAB{4}(m,4)-TAB{4}(m-1,4)).^2);
                    DIST(m,1)=TAB{4}(m,1);
                end

                meanDIST=round(mean(DIST(2:end,2)));

                for m=2:height(TAB{4})
                    DIST(m,2)=DIST(m-1,2)+meanDIST;
                end

                app.TAB=TAB;
                app.MPOL=MPOL;
                app.RAWDATA=RAWDATA;
                app.DIST=DIST;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the array where will be stored the information on
            % profiles (to save the project)

            currentFolder = pwd;
            addpath(append(currentFolder,'\Icons'))

            app.UIFigure.Icon = 'LOGO-provvisorio.png';
            app.UIFigure.Name = 'EEMstudio';

            %%% add icons
            app.UpButton.Icon = 'Up.png';
            app.DownButton.Icon = 'Down.png';
            app.ColorButton.Icon = 'ChangeColor.png';
            app.AddButton.Icon = 'AddProfile.png';
            app.RemoveButton.Icon = 'RemoveProfile.png';
            app.ZoomOnEverithing.Icon = 'ZoomOnAll.png';
            app.ZoomOnSelectedProfile.Icon = 'ZoomOnLayer.png';
            %%%


            %%% add basemap
            % latlim and lonlim are the limits of the map
            % Initial values refers to Italy
            % latEle and lonEle are the max and min coordinates of the
            % plotted electrodes

            app.latlim=[35 48];
            app.lonlim=[6 19];
            app.latEle=[91 -91];
            app.lonEle=[181 -181];
            app.LoadBasemap;

            PROFILES=["FILENAME.QUI" "[R G B]" "PATHNAME"];
            app.PROFILES=PROFILES;

        end

        % Menu selected function: AddprofileMenu
        function AddprofileMenuSelected(app, event)
            app.AddProfile;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.AddProfile;
        end

        % Menu selected function: DeleteprofileMenu
        function DeleteprofileMenuSelected(app, event)
            app.DeleteProfile;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.DeleteProfile;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.selectedNodes = app.Tree.SelectedNodes;

            fileID=fopen(app.Tree.SelectedNodes.Tag);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

            %% sections from .qui file
            %TAB{1}-TAB{10}
            ROW=zeros(1,11);
            i=0;
            for i=1:3
                row_i=strfind(filescan{1}, append('%',num2str(i)));
                row_i=find(~cellfun('isempty',row_i),1);

                ROW(i)=row_i;
            end          % ROW (vector) contains the number of the row of each section

            j=0;
            for j=1:3
                TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-2);
            end          % TAB (cell array) contains in each cell each section

            TAB{1,1}=split(char(TAB{1,1}));
            TAB{1,2}=str2num(char(TAB{1,2}));


            Info=[append('File: ',app.Tree.SelectedNodes.Tag), ' ', ...
                append('Date: ',  string(TAB{1,1}(3))), ...
                append('Location: ',  string(TAB{1,1}(4))), ...
                append('UTM zone: ',  string(TAB{1,1}(2))), ...
                append('Operator: ',  string(TAB{1,1}(5))), ...
                append('NElectrodes: ',  string(TAB{1,2}(2))), ...
                append('NGates: ', string(TAB{1,2}(5)))...
                ];


            app.TextArea.Value=Info;
        end

        % Menu selected function: ProcessMenu
        function ProcessMenuSelected(app, event)
            app.ReadQuiFile;

            if ~isempty(app.Tree.SelectedNodes)
                % CALL PROCESSING APP (in parenthesis the variables to send to it)

                app.QuiFile=app.Tree.SelectedNodes.Tag;
                app.processingapp=processingapp(app,app.TAB,app.MPOL,app.RAWDATA,app.DIST,app.QuiFile);
            end
        end

        % Menu selected function: ProcessAsMenu
        function ProcessAsMenuSelected(app, event)
            app.ReadQuiFile;

            if ~isempty(app.Tree.SelectedNodes)
                % CALL PROCESSING APP (in parenthesis the variables to send to it)

                filter = {'*.qui';'*.quo'};
                [file, path] =  uiputfile(filter);
                
                if file ~= 0
                    NewFile = append(path,file);
                    OldFile = app.Tree.SelectedNodes.Tag;

                    copyfile(NewFile, OldFile);

                    d=find(append(app.PROFILES(:,3),app.PROFILES(:,1))==OldFile);
                    Color=app.PROFILES(d,2);
                    app.PROFILES(d,3)=path;
                    app.PROFILES(d,1)=file;


                    for n=1:height(app.Tree.Children)
                        if strcmp(app.Tree.Children(n).Tag,OldFile)==1
                            treerow=n;
                        end
                    end


                    if ismember(app.Tree.SelectedNodes,app.Tree.CheckedNodes)==1

                        for n=1:height(app.gx.Children)
                            if strcmp(app.gx.Children(n).Tag,OldFile)==1
                                axesrow=n;
                            end
                        end

                        orderAxes1=app.gx.Children(1:axesrow-1);
                        orderAxes3=app.gx.Children(axesrow+1:height(app.gx.Children));
                        delete(app.gx.Children(axesrow));

                        [lon, lat] = app.GeoPlotEle(app.TAB{4}(:,3),app.TAB{4}(:,4));
                        hold(app.gx, 'on')
                        app.PlotElectrodes(treerow,1) = geoscatter(app.gx,lat,lon,'o','MarkerFaceColor',Color,'MarkerEdgeColor',[0 0 0],'Tag',NewFile);

                        orderAxes2=app.gx.Children(1);
                        app.gx.Children=[orderAxes1; orderAxes2; orderAxes3];
                    end

                    app.Tree.SelectedNodes.Text=file;
                    app.Tree.SelectedNodes.Tag=NewFile;

                    TAB1=split(char(app.TAB{1,1}));

                    Info=[append('File: ',NewFile), ' ', ...
                        append('Date: ',  string(TAB1(3))), ...
                        append('Location: ',  string(TAB1(4))), ...
                        append('UTM zone: ',  string(TAB1(2))), ...
                        append('Operator: ',  string(TAB1(5))), ...
                        append('NElectrodes: ',  string(app.TAB{1,2}(2))), ...
                        append('NGates: ', string(app.TAB{1,2}(5)))...
                        ];
                    app.TextArea.Value=Info;
                    uiconfirm(app.UIFigure,'The file associated to the selected profile have been changed.', 'New processing file','Icon','warning', 'Options',{'OK'});

                    app.processingapp=processingapp(app,app.TAB,app.MPOL,app.RAWDATA,app.DIST,NewFile);
                end
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % MOVES UP OF 1 POSITION THE SELECTED TAG IN THE PROFILES TREE
            % AND THE ASSOCIATED CHILDREN IN THE PLOT:
            % Store the current checked nodes in Tags:
            % Find the position of the selected children in the tree then
            % Order the children in the plot and in the tree
            % copying the children in variables and plotting in this order:
            %   - from the first children to the selected -2,
            %   - the selected children,
            %   - the children before the selected one,
            %   - from the children afer the selected one to the end.
            % Empty CheckedNodes and recreate with the new order using Tags

            if ~isempty(app.selectedNodes)
                hold(app.gx,'on')
                checkedNodes = app.Tree.CheckedNodes;

                Tags=strings(height(checkedNodes),1);
                for t=1:height(checkedNodes)
                    Tags(t)=checkedNodes(t).Tag;
                end

                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,app.selectedNodes.Tag)==1
                        treerow=n;
                    end
                end

                if treerow>1
                    if ismember(app.selectedNodes,checkedNodes)==1
                        if ismember(app.Tree.Children(treerow-1),checkedNodes)==1
                            for n=1:height(checkedNodes)
                                if strcmp(checkedNodes(n).Tag,app.selectedNodes.Tag)==1
                                    checkedrow=n;
                                end
                            end
                            checked=checkedNodes(1:checkedrow);

                            if height(checked)>2
                                orderAxes1=app.gx.Children(1:height(checked)-2);
                            end
                            orderAxes2=app.gx.Children(height(checked));
                            orderAxes3=app.gx.Children(height(checked)-1);
                            orderAxes4=app.gx.Children(height(checked)+1:height(app.gx.Children));
                            if height(checked)==2
                                app.gx.Children=[orderAxes2; orderAxes3; orderAxes4];
                            else
                                app.gx.Children=[orderAxes1; orderAxes2; orderAxes3; orderAxes4];
                            end
                        end
                    end


                    if treerow>2
                        orderTree1=app.Tree.Children(1:treerow-2);
                    end
                    orderTree2=app.Tree.Children(treerow);
                    orderTree3=app.Tree.Children(treerow-1);
                    orderTree4=app.Tree.Children(treerow+1:height(app.Tree.Children));

                    if treerow==2
                        app.Tree.Children=[orderTree2; orderTree3; orderTree4];
                    else
                        app.Tree.Children=[orderTree1; orderTree2; orderTree3; orderTree4];
                    end


                    app.Tree.CheckedNodes=[];

                    for c=1:height(app.Tree.Children)
                        if ismember(app.Tree.Children(c).Tag,Tags)==1
                            app.Tree.CheckedNodes=[app.Tree.CheckedNodes; app.Tree.Children(c)];
                        end
                    end
                end
            end


        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % MOVES DOWN OF 1 POSITION THE SELECTED TAG IN THE PROFILES TREE
            % AND THE ASSOCIATED CHILDREN IN THE PLOT:
            % Store the current checked nodes in Tags:
            % Find the position of the selected children in the tree then
            % Order the children in the plot and in the tree
            % copying the children in variables and plotting in this order:
            %   - from the first children to the children before the selected one,
            %   - the children after the selected one,
            %   - the selected children,
            %   - from the selected children +2 to the end.
            % Empty CheckedNodes and recreate with the new order using Tags

            if ~isempty(app.selectedNodes)
                hold(app.gx,'on')
                checkedNodes = app.Tree.CheckedNodes;

                Tags=strings(height(checkedNodes),1);
                numTreeChil=height(app.Tree.Children);

                for t=1:height(checkedNodes)
                    Tags(t)=checkedNodes(t).Tag;
                end

                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,app.selectedNodes.Tag)==1
                        treerow=n;
                    end
                end

                if treerow<numTreeChil
                    if ismember(app.selectedNodes,checkedNodes)==1
                        if ismember(app.Tree.Children(treerow+1),checkedNodes)==1
                            for n=1:height(checkedNodes)
                                if strcmp(checkedNodes(n).Tag,app.selectedNodes.Tag)==1
                                    checkedrow=n;
                                end
                            end
                            checked=checkedNodes(1:checkedrow);

                            orderAxes1=app.gx.Children(1:height(checked)-1);
                            orderAxes2=app.gx.Children(height(checked)+1);
                            orderAxes3=app.gx.Children(height(checked));
                            orderAxes4=app.gx.Children(height(checked)+2:height(app.gx.Children));
                            app.gx.Children=[orderAxes1; orderAxes2; orderAxes3; orderAxes4];
                        end
                    end


                    orderTree1=app.Tree.Children(1:treerow-1);
                    orderTree2=app.Tree.Children(treerow+1);
                    orderTree3=app.Tree.Children(treerow);
                    if treerow<numTreeChil-1
                        orderTree4=app.Tree.Children(treerow+1:height(app.Tree.Children));
                    end

                    if treerow<numTreeChil-1
                        app.Tree.Children=[orderTree1; orderTree2; orderTree3; orderTree4];
                    else
                        app.Tree.Children=[orderTree1; orderTree2; orderTree3];
                    end


                    app.Tree.CheckedNodes=[];

                    for c=1:height(app.Tree.Children)
                        if ismember(app.Tree.Children(c).Tag,Tags)==1
                            app.Tree.CheckedNodes=[app.Tree.CheckedNodes; app.Tree.Children(c)];
                        end
                    end
                end
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % SHOW OR HIDE PROFILE IN THE PLOT BASED ON THE CHECKED NODES

            hold(app.gx, 'on');
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            PROFILES=app.PROFILES;

            PlotElectrodes=app.PlotElectrodes;
            if ismember(selectedNodes,checkedNodes)==0
                for n=1:height(app.gx.Children)
                    if strcmp(app.gx.Children(n).Tag,selectedNodes.Tag)==1
                        axesrow=n;
                    end
                end
                delete(app.gx.Children(axesrow));

            else
                fileID=fopen(selectedNodes.Tag);
                filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                % sections from .qui file
                % TAB{1}-TAB{10}
                ROW=zeros(1,11);
                i=0;
                for i=1:11
                    row_i=strfind(filescan{1}, append('%',num2str(i)));
                    row_i=find(~cellfun('isempty',row_i),1);

                    ROW(i)=row_i;
                end               % ROW (vector) contains the number of the row of each section

                j=0;
                for j=1:10
                    TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                end                % TAB (cell array) contains in each cell each section

                TAB{1,4}=str2num(char(TAB{1,4}));
                for n=1:height(app.Tree.Children)
                    if strcmp(app.Tree.Children(n).Tag,selectedNodes.Tag)==1
                        treerow=n;
                    end
                end

                for n=1:height(checkedNodes)
                    if strcmp(checkedNodes(n).Tag,selectedNodes.Tag)==1
                        checkedrow=n;
                    end
                end
                checked=checkedNodes(1:checkedrow);
                if checkedrow>1
                    orderAxes1=app.gx.Children(1:height(checked)-1);
                end
                orderAxes3=app.gx.Children(height(checked):height(app.gx.Children));

                d=find(append(PROFILES(:,3),PROFILES(:,1))==app.Tree.SelectedNodes.Tag);
                Color=PROFILES(d,2);

                [lon, lat] = app.GeoPlotEle(TAB{4}(:,3),TAB{4}(:,4));
                hold(app.gx, 'on')
                PlotElectrodes(treerow,1) = geoscatter(app.gx,lat,lon,'o','MarkerFaceColor',Color,'MarkerEdgeColor',[0 0 0],'Tag',selectedNodes.Tag);

                orderAxes2=app.gx.Children(1);
                if checkedrow>1
                    app.gx.Children=[orderAxes1; orderAxes2; orderAxes3];
                else
                    app.gx.Children=[orderAxes2; orderAxes3];
                end
            end

            app.PlotElectrodes=PlotElectrodes;
            app.PROFILES=PROFILES;

        end

        % Clicked callback: ColorButton
        function ColorButtonClicked(app, event)
            % CHANGE THE COLOR OF THE POINTS OF THE SELECTED PROFILE IN THE PLOT

            if ~isempty(app.Tree.SelectedNodes)
                hold(app.gx,'on')
                checkedNodes = app.Tree.CheckedNodes;
                selectedNodes = app.Tree.SelectedNodes;
                PROFILES=app.PROFILES;

                PlotElectrodes=app.PlotElectrodes;
                Color = uisetcolor([1 1 0]);

                %d=find(PROFILES(:,1)==app.Tree.SelectedNodes.Tag); 
                d=find(append(PROFILES(:,3),PROFILES(:,1))==app.Tree.SelectedNodes.Tag);
                PROFILES(d,2)=append("[",num2str(Color),"]");

                if ismember(selectedNodes,checkedNodes)==1
                    for n=1:height(app.gx.Children)
                        if strcmp(app.gx.Children(n).Tag,selectedNodes.Tag)==1
                            axesrow=n;
                        end
                    end

                    orderAxes1=app.gx.Children(1:axesrow-1);
                    orderAxes3=app.gx.Children(axesrow+1:height(app.gx.Children));
                    delete(app.gx.Children(axesrow));

                    fileID=fopen(app.Tree.SelectedNodes.Tag);
                    filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

                    % sections from .qui file
                    % TAB{1}-TAB{10}
                    ROW=zeros(1,11);
                    i=0;
                    for i=1:11
                        row_i=strfind(filescan{1}, append('%',num2str(i)));
                        row_i=find(~cellfun('isempty',row_i),1);

                        ROW(i)=row_i;
                    end               % ROW (vector) contains the number of the row of each section

                    j=0;
                    for j=1:10
                        TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
                    end                % TAB (cell array) contains in each cell each section

                    TAB{1,4}=str2num(char(TAB{1,4}));

                    for n=1:height(app.Tree.Children)
                        if strcmp(app.Tree.Children(n).Tag,selectedNodes.Tag)==1
                            treerow=n;
                        end
                    end

                    [lon, lat] = app.GeoPlotEle(TAB{4}(:,3),TAB{4}(:,4));
                    hold(app.gx, 'on')
                    PlotElectrodes(treerow,1) = geoscatter(app.gx,lat,lon,'o','MarkerFaceColor',Color,'MarkerEdgeColor',[0 0 0],'Tag',selectedNodes.Tag);
                    orderAxes2=app.gx.Children(1);
                    app.gx.Children=[orderAxes1; orderAxes2; orderAxes3];
                end
                app.PlotElectrodes=PlotElectrodes;
                app.PROFILES=PROFILES;
            end
        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            % SAVE PROJECT: create a text file in which are stored the
            % profiles in the tree saving (name, color, path) in loading
            % order and the map and the coordinates (UTM) of the edges


            PROFILES=app.PROFILES;
            sortedPROFILES=cell(height(app.Tree.Children),1);

            [projectName,projectPath]=uiputfile('*.txt', 'Save project as');
            addpath(projectPath);
            FID=fopen(append(projectPath,projectName),'w+');

            fprintf(FID,'%s\n','%A Profiles');

            for i=height(app.Tree.Children):-1:1
                d=find(append(PROFILES(:,3), PROFILES(:,1))==app.Tree.Children(i).Tag);

                fprintf(FID,'%s\n',PROFILES(d,1));
                fprintf(FID,'%s\n',PROFILES(d,2));
                fprintf(FID,'%s\n',PROFILES(d,3));
                fprintf(FID,'\n');
            end


            fprintf(FID,'%s\n','%B Map');
            fprintf(FID,'%s\n',app.basemap);
            fprintf(FID,'%12.4f\n',app.latEle);
            fprintf(FID,'%12.4f\n',app.lonEle);
            fprintf(FID,'%12.4f\n',app.latlim);
            fprintf(FID,'%12.4f\n',app.lonlim);

            fprintf(FID,'\n');

            fclose(FID);
        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
            % LOAD PROFILES AND MAP FROM AN EXISTING PROJECT

            if ~isempty(app.Tree.Children)
                YN = uiconfirm(app.UIFigure,'Do you want to save the current project?', 'Open project request');
                if strcmpi(YN,'Save')
                    app.SaveprojectMenuSelected;
                end
            end
            app.ClearAll;


            [projectName,projectPath]=uigetfile('*.txt', 'Open project');
            addpath(projectPath);
            fileID=fopen([projectPath projectName]);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');


            %% Load Profiles
            rowB=strfind(filescan{1}, '%B Map');
            rowB=find(~cellfun('isempty',rowB),1);

            n=(rowB-2)/4;

            for i=1:n
                rowA=1+4*(i-1);
                app.profileName=filescan{1}{rowA+1};
                app.profileColor=filescan{1}{rowA+2};
                app.profilePath=filescan{1}{rowA+3};
                addpath(app.profilePath);
                app.AddProfile;
            end

            %% Load Basemap
            app.basemap=filescan{1}{rowB+1};
            app.latEle=str2num(filescan{1}{rowB+2});
            app.lonEle=str2num(filescan{1}{rowB+3});
            app.latlim=str2num(filescan{1}{rowB+4});
            app.lonlim=str2num(filescan{1}{rowB+5});


            app.LoadBasemap;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            if ~isempty(app.Tree.Children)
                YN = uiconfirm(app.UIFigure,'Do you want to save the current project?', 'Open project request');
                if strcmpi(YN,'Save')
                    app.SaveprojectMenuSelected;
                end
            end
            app.ClearAll;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

        end

        % Menu selected function: LoadbasemapMenu
        function LoadbasemapMenuSelected(app, event)
            app.LoadBasemap;
        end

        % Menu selected function: StreetsLightMenu
        function StreetsLightMenuSelected(app, event)
            app.basemap='streets-light';
            app.LoadBasemap;
        end

        % Menu selected function: StreetsDarkMenu
        function StreetsDarkMenuSelected(app, event)
            app.basemap='streets-dark';
            app.LoadBasemap;
        end

        % Menu selected function: StreetsMenu
        function StreetsMenuSelected(app, event)
            app.basemap='streets';
            app.LoadBasemap;
        end

        % Menu selected function: SatelliteMenu
        function SatelliteMenuSelected(app, event)
            app.basemap='satellite';
            app.LoadBasemap;
        end

        % Menu selected function: TopographicMenu
        function TopographicMenuSelected(app, event)
            app.basemap='topographic';
            app.LoadBasemap;
        end

        % Menu selected function: ColorterrainMenu
        function ColorterrainMenuSelected(app, event)
            app.basemap='colorterrain';
            app.LoadBasemap;
        end

        % Menu selected function: GreyterrainMenu
        function GreyterrainMenuSelected(app, event)
            app.basemap='grayterrain';
            app.LoadBasemap;
        end

        % Menu selected function: LandcoverMenu
        function LandcoverMenuSelected(app, event)
            app.basemap='landcover';
            app.LoadBasemap;
        end

        % Menu selected function: BluegreenMenu
        function BluegreenMenuSelected(app, event)
            app.basemap='bluegreen';
            app.LoadBasemap;
        end

        % Menu selected function: GreylandMenu
        function GreylandMenuSelected(app, event)
            app.basemap='grayland';
            app.LoadBasemap;
        end

        % Menu selected function: DarkwaterMenu
        function DarkwaterMenuSelected(app, event)
            app.basemap='darkwater';
            app.LoadBasemap;
        end

        % Menu selected function: NoneMenu
        function NoneMenuSelected(app, event)
            app.basemap='none';
            app.LoadBasemap;
        end

        % Callback function
        function ZoomonselectionMenuSelected(app, event)
            % Zoom on the profile selected in the tree
            fileID=fopen(app.Tree.SelectedNodes.Tag);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

            %% sections from .qui file
            %TAB{1}-TAB{10}
            ROW=zeros(1,11);
            i=0;
            for i=1:11
                row_i=strfind(filescan{1}, append('%',num2str(i)));
                row_i=find(~cellfun('isempty',row_i),1);

                ROW(i)=row_i;
            end               % ROW (vector) contains the number of the row of each section

            j=0;
            for j=1:10
                TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
            end                % TAB (cell array) contains in each cell each section

            TAB{1,1}=split(char(TAB{1,1}(1)));
            utmZone=string(TAB{1,1}(2));
            app.zona=append(utmZone{1}(1:2),' ',utmZone{1}(3));

            TAB{1,3}=str2num(char(TAB{1,3}));
            TAB{1,4}=str2num(char(TAB{1,4}));

            TAB{1,6}=str2num(char(TAB{1,6}));
            TAB{1,7}=str2num(char(TAB{1,7}));
            TAB{1,8}=str2num(char(TAB{1,8}));

            [lon, lat] = app.GeoPlotEle(TAB{4}(:,3),TAB{4}(:,4));

            app.latlim = [min(lat) max(lat)];
            app.lonlim = [min(lon) max(lon)];
            app.LoadBasemap;
        end

        % Callback function
        function ZoomonalllayersMenuSelected(app, event)
            % Zoom on all the profiles in the project
            app.latlim = app.latEle;
            app.lonlim = app.lonEle;
            app.LoadBasemap;
        end

        % Callback function
        function AddRasterMenuSelected(app, event)
            [filenameMap,pathnameMap]=uigetfile('*.tif');
            if filenameMap ~= 0
                addpath(pathnameMap);

                path=append(pathnameMap, filenameMap);

                [Z,R] = readgeoraster(path,'OutputType','double');
                info = georasterinfo(path);

                p = R.ProjectedCRS;
                ellipsoid = p.GeographicCRS.Spheroid;

                [M,N] = size(Z);
                xCenterIntrinsic = (1 + N)/2;
                yCenterIntrinsic = (1 + M)/2;
                [xCenter,yCenter] = intrinsicToWorld(R,xCenterIntrinsic,yCenterIntrinsic);
                [latCenter,lonCenter] = projinv(p,xCenter,yCenter);

                utmZone = utmzone(latCenter,lonCenter);


                %%% FOR SHAPE:   https://it.mathworks.com/help/map/ref/geopointshape.geoplot.html#mw_acd21514-7ad8-44b5-97ef-a9e24279d659


            end

        end

        % Clicked callback: ZoomOnSelectedProfile
        function ZoomOnSelectedProfileClicked(app, event)
            fileID=fopen(app.Tree.SelectedNodes.Tag);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

            %% sections from .qui file
            %TAB{1}-TAB{10}
            ROW=zeros(1,11);
            i=0;
            for i=1:11
                row_i=strfind(filescan{1}, append('%',num2str(i)));
                row_i=find(~cellfun('isempty',row_i),1);

                ROW(i)=row_i;
            end               % ROW (vector) contains the number of the row of each section

            j=0;
            for j=1:10
                TAB{j}=filescan{1}(ROW(j)+1:ROW(j+1)-1);
            end                % TAB (cell array) contains in each cell each section

            TAB{1,1}=split(char(TAB{1,1}(1)));
            utmZone=string(TAB{1,1}(2));
            app.zona=append(utmZone{1}(1:2),' ',utmZone{1}(3));

            TAB{1,3}=str2num(char(TAB{1,3}));
            TAB{1,4}=str2num(char(TAB{1,4}));

            TAB{1,6}=str2num(char(TAB{1,6}));
            TAB{1,7}=str2num(char(TAB{1,7}));
            TAB{1,8}=str2num(char(TAB{1,8}));

            [lon, lat] = app.GeoPlotEle(TAB{4}(:,3),TAB{4}(:,4));

            app.latlim = [min(lat) max(lat)];
            app.lonlim = [min(lon) max(lon)];
            app.LoadBasemap;
        end

        % Clicked callback: ZoomOnEverithing
        function ZoomOnEverithingClicked(app, event)
            % Zoom on all the profiles in the project
            app.latlim = app.latEle;
            app.lonlim = app.lonEle;
            app.LoadBasemap;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.Text = 'Home';
            app.NewprojectMenu = uimenu(app.HomeMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project';
            app.OpenprojectMenu = uimenu(app.HomeMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project';
            app.SaveprojectMenu = uimenu(app.HomeMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project';
            app.AddprofileMenu = uimenu(app.HomeMenu);
            app.AddprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @AddprofileMenuSelected, true);
            app.AddprofileMenu.Text = 'Add profile ';
            app.DeleteprofileMenu = uimenu(app.HomeMenu);
            app.DeleteprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteprofileMenuSelected, true);
            app.DeleteprofileMenu.Text = 'Delete profile';
            app.MapMenu = uimenu(app.UIFigure);
            app.MapMenu.Text = 'Map';
            app.LoadbasemapMenu = uimenu(app.MapMenu);
            app.LoadbasemapMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadbasemapMenuSelected, true);
            app.LoadbasemapMenu.Text = 'Load basemap';
            app.StreetsLightMenu = uimenu(app.LoadbasemapMenu);
            app.StreetsLightMenu.MenuSelectedFcn = createCallbackFcn(app, @StreetsLightMenuSelected, true);
            app.StreetsLightMenu.Text = 'Streets Light';
            app.StreetsDarkMenu = uimenu(app.LoadbasemapMenu);
            app.StreetsDarkMenu.MenuSelectedFcn = createCallbackFcn(app, @StreetsDarkMenuSelected, true);
            app.StreetsDarkMenu.Text = 'Streets Dark';
            app.StreetsMenu = uimenu(app.LoadbasemapMenu);
            app.StreetsMenu.MenuSelectedFcn = createCallbackFcn(app, @StreetsMenuSelected, true);
            app.StreetsMenu.Text = 'Streets';
            app.SatelliteMenu = uimenu(app.LoadbasemapMenu);
            app.SatelliteMenu.MenuSelectedFcn = createCallbackFcn(app, @SatelliteMenuSelected, true);
            app.SatelliteMenu.Text = 'Satellite';
            app.TopographicMenu = uimenu(app.LoadbasemapMenu);
            app.TopographicMenu.MenuSelectedFcn = createCallbackFcn(app, @TopographicMenuSelected, true);
            app.TopographicMenu.Text = 'Topographic';
            app.ColorterrainMenu = uimenu(app.LoadbasemapMenu);
            app.ColorterrainMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorterrainMenuSelected, true);
            app.ColorterrainMenu.Text = 'Colorterrain';
            app.GreyterrainMenu = uimenu(app.LoadbasemapMenu);
            app.GreyterrainMenu.MenuSelectedFcn = createCallbackFcn(app, @GreyterrainMenuSelected, true);
            app.GreyterrainMenu.Text = 'Greyterrain';
            app.LandcoverMenu = uimenu(app.LoadbasemapMenu);
            app.LandcoverMenu.MenuSelectedFcn = createCallbackFcn(app, @LandcoverMenuSelected, true);
            app.LandcoverMenu.Text = 'Landcover';
            app.BluegreenMenu = uimenu(app.LoadbasemapMenu);
            app.BluegreenMenu.MenuSelectedFcn = createCallbackFcn(app, @BluegreenMenuSelected, true);
            app.BluegreenMenu.Text = 'Bluegreen';
            app.GreylandMenu = uimenu(app.LoadbasemapMenu);
            app.GreylandMenu.MenuSelectedFcn = createCallbackFcn(app, @GreylandMenuSelected, true);
            app.GreylandMenu.Text = 'Greyland';
            app.DarkwaterMenu = uimenu(app.LoadbasemapMenu);
            app.DarkwaterMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkwaterMenuSelected, true);
            app.DarkwaterMenu.Text = 'Darkwater';
            app.NoneMenu = uimenu(app.LoadbasemapMenu);
            app.NoneMenu.MenuSelectedFcn = createCallbackFcn(app, @NoneMenuSelected, true);
            app.NoneMenu.Text = 'None';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = 'Menu';
            app.ProcessMenu = uimenu(app.Menu_2);
            app.ProcessMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuSelected, true);
            app.ProcessMenu.Text = 'Process...';
            app.ProcessAsMenu = uimenu(app.Menu_2);
            app.ProcessAsMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessAsMenuSelected, true);
            app.ProcessAsMenu.Text = 'Process As...';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ColorButton = uipushtool(app.Toolbar);
            app.ColorButton.ClickedCallback = createCallbackFcn(app, @ColorButtonClicked, true);
            app.ColorButton.Icon = 'ChangeColor.png';
            app.ZoomOnSelectedProfile = uipushtool(app.Toolbar);
            app.ZoomOnSelectedProfile.ClickedCallback = createCallbackFcn(app, @ZoomOnSelectedProfileClicked, true);
            app.ZoomOnSelectedProfile.Icon = 'ZoomOnLayer.png';
            app.ZoomOnEverithing = uipushtool(app.Toolbar);
            app.ZoomOnEverithing.ClickedCallback = createCallbackFcn(app, @ZoomOnEverithingClicked, true);
            app.ZoomOnEverithing.Icon = 'ZoomOnAll.png';
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LayersTab = uitab(app.TabGroup);
            app.LayersTab.Title = 'Layers';
            app.Tree = uitree(app.LayersTab, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.UpButton = uibutton(app.LayersTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Icon = 'Up.png';
            app.UpButton.Text = '';
            app.DownButton = uibutton(app.LayersTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Icon = 'Down.png';
            app.DownButton.Text = '';
            app.AddButton = uibutton(app.LayersTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = 'AddProfile.png';
            app.AddButton.Text = '';
            app.RemoveButton = uibutton(app.LayersTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Icon = 'RemoveProfile.png';
            app.RemoveButton.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.Panel_3 = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gisapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end