classdef processingapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ListofshortcutsMenu             matlab.ui.container.Menu
        ForwardEditField                matlab.ui.control.EditField
        AddforwardfileButton            matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        UpdateintMvaluesButton          matlab.ui.control.Button
        HiderejectedpointsCheckBox      matlab.ui.control.CheckBox
        SaveprocessingButton            matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DataprofileandPseudosectionTab  matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        TypeDropDown_profile            matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        SettingsButton_profile          matlab.ui.control.Button
        CurveshiftfactorSpinner         matlab.ui.control.Spinner
        CurveshiftfactorSpinnerLabel    matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        SettingsButton_pseudosec        matlab.ui.control.Button
        ColorbarEditorButton            matlab.ui.control.Button
        TypeDropDown_pseudosection      matlab.ui.control.DropDown
        TypeDropDownLabel_2             matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        DatastripeTab                   matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        UIAxes3_2                       matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        HideerrorbarsCheckBox           matlab.ui.control.CheckBox
        TypeDropDown_stripe             matlab.ui.control.DropDown
        TypeDropDown_3Label             matlab.ui.control.Label
        SettingsButton_stripe           matlab.ui.control.Button
        Next                            matlab.ui.control.Button
        Previous                        matlab.ui.control.Button
        PatchworkTab                    matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        UIAxes3_3                       matlab.ui.control.UIAxes
        Panel_7                         matlab.ui.container.Panel
        ColorbarEditorButton_2          matlab.ui.control.Button
        TypeDropDown_patchwork          matlab.ui.control.DropDown
        TypeDropDown_3Label_2           matlab.ui.control.Label
        SettingsButton_patchwork        matlab.ui.control.Button
        DecaysPanel                     matlab.ui.container.Panel
        TypeDropDown_decays             matlab.ui.control.DropDown
        TypeDropDown_2Label             matlab.ui.control.Label
        RawdataButton                   matlab.ui.control.Button
        SettingsButton_decay            matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        PositionPanel                   matlab.ui.container.Panel
        SettingsButton_positions        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        ProfileTab                      matlab.ui.container.Tab
        HideallButton                   matlab.ui.control.Button
        ShowallButton                   matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
    end


    %% Recurrent comments:
    %  - if the wisth of MPOL is less than 25 is a .qui/.quo file
    %    containing only resistivity data;


    properties (Access = private)

        %% To call other apps
        callingapp  % gisapp
        % Settings apps
        settings_profileapp
        settings_pseudosecapp
        settings_stripeapp
        settings_decayapp
        settings_positionsapp
        settings_patchworkapp


        %% Input from gisapp
        TAB         % Tables %1-10 of .qui/.quo file

        MPOL        % Table %11 of .qui/.quo file
        % WHERE: startupFcn

        RAWDATA     % Rawdata name from table %11 of .qui/.quo file

        DIST        % Distance between electrodes
        % WHERE: startupFcn

        QuiFile     % Name of .qui/.quo file

        %%
        NGates                 % Number of gates

        NameSetPdepthSide      % array of strings nx3: strings with pseudodepth
        % and multipole geometry of each cell of app.SetPdepthSide

        SetPdepthSide          % cell array nx3
        % Rows: measurements divided by pseudodepth,
        % Columns: measurements divided by symmetry
        % (symmetric, asymmetric left, asymmetric
        % right)
        % In non-empty cells: nx6 with n number of
        % measurements with the same pseudodepth and
        % symmetry
        % Columns: 1) ID (row in MPOL)
        %          2) Lateral focus
        %          3) Pseudodepth
        %          4) Apparent resistivity
        %          5) Integral chargeability (if there is
        %             also in .qui/.quo file)
        %          6) Symmetry
        %             [0: symmetric, 1: left, 2: right]
        % WHERE: startupFcn


        nodeDataProfile        % cell array mxn
        % m: number of pseudodepth sets
        % n=3: geometry (symmmetric, left or right)
        % nodes of the Data Profile tree
        % WHERE: startupFcn

        ROWSChecked            % array
        % List of rows in MPOL that correspond to a checked node
        % WHERE: startupFcn

        GATES                  % array nx5 with n number of chargeability gates
        % is tab %7 of .qui/.quo file excluding the
        % DC gate
        % WHERE: startupFcn

        ABMN                   % array nx4 containing all the quadrupoles
        % configurations expressed in distance of
        % the electrode from the start of the line
        % Columns: 1) A; 2) B; 3) M; 4) N
        % WHERE: startupFcn

        calcXfocus             % array nx1 with n=height(MPOL) with calculated
        % values of lateral focus
        % WHERE: startupFcn, CalculateXfocus

        calcPdepth             % array nx1 with n=height(MPOL) with calculated
        % values of pseudodepth
        % WHERE: startupFcn, CalculatePdepth


        %% For Data Profiles
        MPOLRhoaShift          %

        %% For Data Stripe and Patchwork
        MSet
        LineMSet
        Xfocus
        Std_neg
        Std_pos

        %% Plots
        plotDataProfile
        plotSelectedProfile

        %% Flags for commenting data
        FLAGS               % 3-D array (i,j,k) of flags [0: keep the data,
        %                             1: comment (reject) the data]
        % i: height(MPOL)
        % j: 1) rhoa flag
        %    2) intM flag
        %    3-end) gate-by-gate M
        % k: repetitions of (i,j) arrays for Ctrl+Z and Ctrl+Y
        % WHERE: startupFcn

        kMid                % current FLAGS(i,j) that we update commenting
        % WHERE: startupFcn

        kEnd                % max val of k for FLAGS (i,j,k)
        % WHERE: startupFcn

        rowFLAGSxDecays
        rowFLAGSxRhoa
        rowFLAGS
        colFLAGS

        %% Measurements shown in decays
        ShownPositions
        ShownProfile
        ShownPseudosec

        %% Containing axis values that can be changed in settings
        settingsProfile         %
        % WHERE: startupFcn

        settingsPseudosec       %
        % WHERE: startupFcn

        settingsStripe          %
        % WHERE: startupFcn

        settingsDecay           %
        % WHERE: startupFcn

        settingsPositions       %
        % WHERE: startupFcn

        settingsPatchwork       %
        % WHERE: startupFcn


        %% Limits for plots and colorbars

        MinDist, MaxDist        % Distance from the start of the profile
        % WHERE: startupFcn

        MinRhoa, MaxRhoa        % Rhoa
        % WHERE: startupFcn

        MinIntM10, MaxIntM90    % intM (10% and 90% of absolute value)
        % WHERE: startupFcn

        MinAbsM, MaxAbsM        % M (absolute value, min > 0)
        % WHERE: startupFcn

        MaxAsinhM, MinAsinhM    % M (10% and 90% of inverse hyperbolic sine)
        % WHERE: startupFcn

        MinRhoa10, MaxRhoa90    % Rhoa (10% and 90% of absolute value)
        % WHERE: startupFcn


        %% Related to forward file
        ForwardFile
        fMPOL
        fSetPdepthSide

        %% Related to the misfit observed/forward data
        mMPOL
        mSetPdepthSide

        %% Identifiers for identification of measurements
        Frame      % Indicates which tab in the central part of the window
        % is on the front, only these plots will be active
        % WHERE: startupFcn

        Parameter
        Type
        Graph

        %% Manually created colorscales
        MisfitCM   % Misfit (for Pseudosection and Patchwork)
        % WHERE: startupFcn

        MCM        % Chargeability (for Patchwork, with negative values)
        % WHERE: startupFcn

    end

    methods (Access = public)

        % FUNCTIONS TO IMPORT DATA FROM SETTINGS APP

        function importdata_settings_profile(app,settingsProfile)
            app.UIAxes2.XLim=settingsProfile{2}(1,:);
            app.UIAxes2.YLim=settingsProfile{2}(2,:);
            app.settingsProfile=settingsProfile;
        end

        function importdata_settings_pseudosec(app,settingsPseudosec)
            app.UIAxes3.XLim=settingsPseudosec{2}(1,:);
            app.UIAxes3.YLim=settingsPseudosec{2}(2,:);

            app.settingsPseudosec=settingsPseudosec;
        end

        function importdata_settings_stripe(app,settingsStripe)
            app.UIAxes3_2.XLim=settingsStripe{2}(1,:);
            app.UIAxes3_2.YLim=settingsStripe{2}(2,:);

            app.settingsStripe=settingsStripe;

            app.Next.Enable = 'on';
            app.Previous.Enable = 'on';

            if app.UIAxes3_2.XLim(1,1)<=min(app.DIST(:,2))
                app.Previous.Enable = 'off';
            end
            if app.UIAxes3_2.XLim(1,2)>=max(app.DIST(:,2))
                app.Next.Enable = 'off';
            end

        end

        function importdata_settings_decay(app,settingsDecay)
            app.UIAxes4.XLim=settingsDecay{2}(1,:);
            app.UIAxes4.YLim=settingsDecay{2}(2,:);
            app.settingsDecay=settingsDecay;
        end

        function importdata_settings_positions(app,settingsPositions)
            app.UIAxes.XLim=settingsPositions{2}(1,:);
            app.UIAxes.YLim=settingsPositions{2}(2,:);
            app.settingsPositions=settingsPositions;
        end

        function importdata_settings_patchwork(app,settingsPatchwork)
            app.UIAxes3_3.XLim=settingsPatchwork{2}(1,:);
            app.UIAxes3_3.YLim=settingsPatchwork{2}(2,:);
            app.settingsPatchwork=settingsPatchwork;
        end
    end

    methods (Access = private)

        function EnableKeyboardShortCuts(app)
            %% get the brush handle
            h=brush(app.UIFigure);
            %enable brush on all UIFigure axes; this resets the keypress
            h.Enable='on';
            %% allow to change listeners, so we can re-assign keyboard
            hManager = uigetmodemanager(app.UIFigure);
            [hManager.WindowListenerHandles.Enabled] = deal(false);
            %% re-assign keyboard callback
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
        end


        %%%%%% PLOTS FUNCTIONS %%%%%%%%

        function PlotPosition(app)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.DIST(:,2),app.TAB{4}(:,5), 'k.');
        end


        function PlotDataProfile(app)
            if app.Frame==1
                cla(app.UIAxes2)


                checkedNodes = app.Tree.CheckedNodes;

                CShiftvalue = app.CurveshiftfactorSpinner.Value;
                TextcheckedNodes=strings(height(checkedNodes),1);

                for i=1:height(checkedNodes)
                    TextcheckedNodes(i)=checkedNodes(i).Text;
                end

                if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)')
                    ind=1;
                elseif isequal(app.TypeDropDown_profile.Value,'intM (data)') || isequal(app.TypeDropDown_profile.Value,'intM (forward)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                    ind=2;
                end

                if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'intM (data)')
                    SetPdepthSide=app.SetPdepthSide;
                    colorLine='#1F1F1F';
                    isMisfit=0;

                elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_profile.Value,'intM (forward)')
                    SetPdepthSide=app.fSetPdepthSide;
                    colorLine=[0 0 1];
                    isMisfit=0;

                elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                    SetPdepthSide=app.mSetPdepthSide;
                    colorLine='#1F1F1F'	;
                    isMisfit=1;
                end

                app.MPOLRhoaShift=zeros(height(app.MPOL),1);
                for m=1:height(app.SetPdepthSide)
                    for n=1:3

                        if ~isempty(app.SetPdepthSide{m,n})

                            index=height(SetPdepthSide)-(m-1);
                            RhoaShift=zeros(height(SetPdepthSide{m,n}),1);

                            for p=1:height(SetPdepthSide{m,n})

                                RhoaShift(p,1)=SetPdepthSide{m,n}(p,ind+3).*CShiftvalue^index;  %% WARNING! RhoaShift is only the name, can be also used for intM
                                if isMisfit==1 && SetPdepthSide{m,n}(p,ind+3)==9999
                                    RhoaShift(p,1)=0;
                                end
                                app.MPOLRhoaShift(SetPdepthSide{m,n}(p,1),1)=RhoaShift(p,1);
                            end


                            if ismember(app.NameSetPdepthSide{m,n},TextcheckedNodes)==1
                                if app.HiderejectedpointsCheckBox.Value==0
                                    if  isMisfit==1
                                        d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==1 & SetPdepthSide{m,n}(:,ind+3)~=9999);
                                        d2=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==1 & SetPdepthSide{m,n}(:,ind+3)==9999);
                                    else
                                        d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==1);
                                    end

                                    if ~isempty(d)
                                        if isMisfit==1
                                            stem (app.UIAxes2,SetPdepthSide{m,n}(d,2),RhoaShift(d,1),'.','Color',[0.7 0.7 0.7],'Linestyle',"-",'BaseValue',1);
                                            scatter (app.UIAxes2,SetPdepthSide{m,n}(d2,2),RhoaShift(d2,1),'x','MarkerEdgeColor',[0.7 0.7 0.7],"LineWidth",0.7);
                                        else
                                            plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),RhoaShift(:,1),'-','Color',[0.7 0.7 0.7]);
                                            plot(app.UIAxes2,SetPdepthSide{m,n}(d,2),RhoaShift(d,1),'.','Color',[0.7 0.7 0.7],'Linestyle',"none");
                                        end
                                    end
                                end

                                if  isMisfit==1
                                    d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==0 & SetPdepthSide{m,n}(:,ind+3)~=9999);
                                    d2=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==0 & SetPdepthSide{m,n}(:,ind+3)==9999);
                                else
                                    d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==0);
                                    d=d';
                                    Dd=diff([0,diff(d)==1,0]);
                                    firstd=d(Dd>0);
                                    lastd=d(Dd<0);
                                end

                                if ~isempty(d)
                                    if isMisfit==1
                                        stem (app.UIAxes2,SetPdepthSide{m,n}(d,2),RhoaShift(d,1),'.','Color',colorLine,'Linestyle',"-",'BaseValue',1);
                                        scatter (app.UIAxes2,SetPdepthSide{m,n}(d2,2),RhoaShift(d2,1),'x','MarkerEdgeColor',colorLine,"LineWidth",0.7);
                                    else
                                        for e=1:length(firstd)
                                            plot(app.UIAxes2,SetPdepthSide{m,n}(firstd(e):lastd(e),2),RhoaShift(firstd(e):lastd(e),1),'-','Color',colorLine);
                                        end
                                        plot(app.UIAxes2,SetPdepthSide{m,n}(d,2),RhoaShift(d,1),'.','Color',colorLine,'LineStyle',"none");
                                    end
                                end

                            end
                        end
                    end

                end
            end
        end


        function PlotPseudosection(app)
            if app.Frame==1
                cla(app.UIAxes3)

                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)')
                    ind=1;
                elseif isequal(app.TypeDropDown_pseudosection.Value,'intM (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    ind=2;
                end

                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (data)')
                    SetPdepthSide=app.SetPdepthSide;

                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)')
                    SetPdepthSide=app.fSetPdepthSide;

                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    SetPdepthSide=app.mSetPdepthSide;
                end

                for m=1:height(SetPdepthSide)
                    for n=1:3
                        if ~isempty(app.SetPdepthSide{m,n})
                            if app.HiderejectedpointsCheckBox.Value==0
                                d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==1);
                                scatter(app.UIAxes3,SetPdepthSide{m,n}(d,2),SetPdepthSide{m,n}(d,3),70,[0.7 0.7 0.7],'filled','s');
                            end

                            d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==0 & SetPdepthSide{m,n}(:,ind+3)~=9999 & SetPdepthSide{m,n}(:,ind+3)~=0);
                            color=SetPdepthSide{m,n}(d,ind+3);
                            scatter(app.UIAxes3,SetPdepthSide{m,n}(d,2),SetPdepthSide{m,n}(d,3),70,color,'filled','s');

                            d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==0 & SetPdepthSide{m,n}(:,ind+3)==9999);
                            scatter(app.UIAxes3,SetPdepthSide{m,n}(d,2),SetPdepthSide{m,n}(d,3),60,'x',"MarkerEdgeColor",[0 0 0],'LineWidth',0.7);

                            d=find(app.FLAGS(SetPdepthSide{m,n}(:,1),ind,app.kMid)==0 & SetPdepthSide{m,n}(:,ind+3)==0);
                            scatter(app.UIAxes3,SetPdepthSide{m,n}(d,2),SetPdepthSide{m,n}(d,3),60,'s',"MarkerEdgeColor",[0 0 0],'LineWidth',1);

                        end
                    end
                end
            end
        end


        function PlotDecay(app)
            cla(app.UIAxes4)

            %% Line
            if isequal(app.TypeDropDown_decays.Value,'M (data)')
                MPOL=app.MPOL;
                colorLine='#1F1F1F';
            elseif isequal(app.TypeDropDown_decays.Value,'M (data + forward)')
                MPOL=app.fMPOL;
                colorLine=[0 0 1];
            end

            for j=1:height(app.rowFLAGSxDecays)

                eg=find(app.FLAGS(app.rowFLAGSxDecays(j),3:app.NGates+1,10)==1);
                eb=find(app.FLAGS(app.rowFLAGSxDecays(j),3:app.NGates+1,10)==0 & MPOL(app.rowFLAGSxDecays(j),26+app.NGates*2:24+app.NGates*3)>0);
                er=find(app.FLAGS(app.rowFLAGSxDecays(j),3:app.NGates+1,10)==0 & MPOL(app.rowFLAGSxDecays(j),26+app.NGates*2:24+app.NGates*3)<=0);


                if app.HiderejectedpointsCheckBox.Value==0
                    %% Grey
                    plotDecay(app.rowFLAGSxDecays(j),1)=plot(app.UIAxes4,abs(app.GATES(1:app.NGates-1,3)),abs(MPOL(app.rowFLAGSxDecays(j),26+app.NGates*2:24+app.NGates*3)),'-','LineWidth',0.5,'Color',[0.7 0.7 0.7]);
                end

                Deb=diff([0,diff(eb)==1,0]);
                firsteb=eb(Deb>0);
                lasteb=eb(Deb<0);

                ebr=sort([eb er]);
                Debr=diff([0,diff(ebr)==1,0]);
                firstebr=ebr(Debr>0);
                lastebr=ebr(Debr<0);

                Der=diff([0,diff(er)==1,0]);
                firster=er(Der>0);
                laster=er(Der<0);

                %% Red
                for e=1:length(firstebr)
                    plotDecay(app.rowFLAGSxDecays(j),4)=plot(app.UIAxes4,abs(app.GATES(firstebr(e):lastebr(e),3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+firstebr(e):25+app.NGates*2+lastebr(e))),'-','LineWidth',0.5,'Color',[1 0 0]);
                end

                %% Black
                for e=1:length(firsteb)
                    plotDecay(app.rowFLAGSxDecays(j),7)=plot(app.UIAxes4,abs(app.GATES(firsteb(e):lasteb(e),3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+firsteb(e):25+app.NGates*2+lasteb(e))),'-','LineWidth',0.5,'Color',colorLine);
                end

            end


            %% Points + bars

            MPOL=app.MPOL;
            for j=1:height(app.rowFLAGSxDecays)

                eg=find(app.FLAGS(app.rowFLAGSxDecays(j),3:app.NGates+1,10)==1);
                eb=find(app.FLAGS(app.rowFLAGSxDecays(j),3:app.NGates+1,10)==0 & MPOL(app.rowFLAGSxDecays(j),26+app.NGates*2:24+app.NGates*3)>0);
                er=find(app.FLAGS(app.rowFLAGSxDecays(j),3:app.NGates+1,10)==0 & MPOL(app.rowFLAGSxDecays(j),26+app.NGates*2:24+app.NGates*3)<=0);


                if app.HiderejectedpointsCheckBox.Value==0
                    %% Grey

                    if ~isempty(eg)
                        STDneg= abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eg)) ./ (1+abs(MPOL(app.rowFLAGSxDecays(j),19+eg))) - abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eg));
                        STDpos= abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eg)) .* (1+abs(MPOL(app.rowFLAGSxDecays(j),19+eg))) - abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eg));
                        plotDecay(app.rowFLAGSxDecays(j),2)=errorbar(app.UIAxes4,abs(app.GATES(eg,3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eg)),STDneg,STDpos,'.','Color',[0.7 0.7 0.7],'LineStyle','none');
                        plotDecay(app.rowFLAGSxDecays(j),3)=plot(app.UIAxes4,abs(app.GATES(eg,3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eg)),'MarkerFaceColor',[0.7 0.7 0.7],'MarkerEdgeColor',[0.7 0.7 0.7],'Marker','o','MarkerSize',2,'LineStyle','none','Tag',num2str(app.rowFLAGSxDecays(j)));

                    end
                end


                %% Red
                if ~isempty(er)
                    STDneg=abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+er)) ./ (1+abs(MPOL(app.rowFLAGSxDecays(j),19+er))) - abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+er));
                    STDpos=abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+er)) .* (1+abs(MPOL(app.rowFLAGSxDecays(j),19+er))) - abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+er));
                    plotDecay(app.rowFLAGSxDecays(j),5)=errorbar(app.UIAxes4,abs(app.GATES(er,3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+er)),STDneg,STDpos,'.','Color',[1 0 0],'LineStyle','none');
                    plotDecay(app.rowFLAGSxDecays(j),6)=plot(app.UIAxes4,abs(app.GATES(er,3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+er)),'o','MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 0 0],'MarkerSize',2,'LineStyle','none','Tag',num2str(app.rowFLAGSxDecays(j)));
                end


                %% Black
                if ~isempty(eb)
                    STDneg=abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eb)) ./ (1+abs(MPOL(app.rowFLAGSxDecays(j),19+eb))) - abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eb));
                    STDpos=abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eb)) .* (1+abs(MPOL(app.rowFLAGSxDecays(j),19+eb))) - abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eb));
                    plotDecay(app.rowFLAGSxDecays(j),8)=errorbar(app.UIAxes4,abs(app.GATES(eb,3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eb)),STDneg,STDpos,'.','Color','#1F1F1F','LineStyle','none');
                    plotDecay(app.rowFLAGSxDecays(j),9)=plot(app.UIAxes4,abs(app.GATES(eb,3)),abs(MPOL(app.rowFLAGSxDecays(j),25+app.NGates*2+eb)),'o','MarkerFaceColor','#1F1F1F','MarkerEdgeColor','#1F1F1F','MarkerSize',2,'LineStyle','none','Tag',num2str(app.rowFLAGSxDecays(j)));
                end

            end
        end


        function PlotDataStripe(app)
            if app.Frame==2
                cla(app.UIAxes3_2)

                selectedNodes = app.Tree.SelectedNodes;

                [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

                hSet=height(app.SetPdepthSide{m,n});

                if isequal(app.TypeDropDown_stripe.Value,'M (data)')
                    MPOL=app.MPOL;
                    colorLine='#1F1F1F';
                elseif isequal(app.TypeDropDown_stripe.Value,'M (data + forward)')
                    MPOL=app.fMPOL;
                    colorLine=[0 0 1];
                end

                if isempty(app.LineMSet{1})
                    for g=1:app.NGates-1
                        for h=1:hSet
                            app.LineMSet{g}(h)=MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g);
                        end
                    end
                end

                MPOL=app.MPOL;

                if isempty(app.MSet{1})
                    for g=1:app.NGates-1
                        for h=1:hSet
                            app.MSet{g}(h)=MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g);
                            app.Xfocus{g}(h)=app.SetPdepthSide{m,n}(h,2);
                            app.Std_neg{g}(h)=abs(MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g)) ./ (1+abs(MPOL(app.SetPdepthSide{m,n}(h,1),19+g))) - abs(MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g));
                            app.Std_pos{g}(h)=abs(MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g)) .* (1+abs(MPOL(app.SetPdepthSide{m,n}(h,1),19+g))) - abs(MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g));
                        end
                    end
                end


                %% Line
                for g=1:app.NGates-1

                    for h=1:hSet
                        Flags{g}(h)=app.FLAGS(app.SetPdepthSide{m,n}(h,1),g+2,10);
                    end

                    gg=find(Flags{g}==1);
                    gb=find(Flags{g}==0 & app.LineMSet{g}>0);
                    gr=find(Flags{g}==0 & app.LineMSet{g}<=0);

                    if app.HiderejectedpointsCheckBox.Value==0
                        %% Grey
                        plotStripe(g,1)=plot(app.UIAxes3_2,app.Xfocus{g},abs(app.LineMSet{g}),'-','LineWidth',0.5,'Color',[0.7 0.7 0.7]);
                    end

                    gbr=sort([gb gr]);
                    Dgbr=diff([0,diff(gbr)==1,0]);
                    firstgbr=gbr(Dgbr>0);
                    lastgbr=gbr(Dgbr<0);

                    Dgb=diff([0,diff(gb)==1,0]);
                    firstgb=gb(Dgb>0);
                    lastgb=gb(Dgb<0);

                    %% Red
                    for e=1:length(firstgbr)
                        plotStripe(g,4)=plot(app.UIAxes3_2,app.Xfocus{g}(firstgbr(e):lastgbr(e)),abs(app.LineMSet{g}(firstgbr(e):lastgbr(e))),'-','LineWidth',0.5,'Color',[1 0 0]);
                    end

                    %% Black
                    for e=1:length(firstgb)
                        plotStripe(g,7)=plot(app.UIAxes3_2,app.Xfocus{g}(firstgb(e):lastgb(e)),abs(app.LineMSet{g}(firstgb(e):lastgb(e))),'-','LineWidth',0.5,'Color',colorLine);
                    end
                end

                %% Points + bars

                for g=1:app.NGates-1

                    for h=1:hSet
                        Flags{g}(h)=app.FLAGS(app.SetPdepthSide{m,n}(h,1),g+2,10);
                    end

                    gg=find(Flags{g}==1);
                    gb=find(Flags{g}==0 & app.MSet{g}>0);
                    gr=find(Flags{g}==0 & app.MSet{g}<=0);

                    if app.HiderejectedpointsCheckBox.Value==0
                        %% Grey
                        if ~isempty(gg)
                            if app.HideerrorbarsCheckBox.Value==0
                                plotStripe(g,2)=errorbar(app.UIAxes3_2,app.Xfocus{g}(gg),abs(app.MSet{g}(gg)),app.Std_neg{g}(gg),app.Std_pos{g}(gg),'.','Color',[0.7 0.7 0.7],'LineStyle','none');
                            end
                            plotStripe(g,3)=plot(app.UIAxes3_2,app.Xfocus{g}(gg),abs(app.MSet{g}(gg)),'MarkerFaceColor',[0.7 0.7 0.7],'MarkerEdgeColor',[0.7 0.7 0.7],'Marker','o','MarkerSize',2,'LineStyle','none','Tag',num2str(g));
                        end
                    end

                    %% Red
                    if ~isempty(gr)
                        if app.HideerrorbarsCheckBox.Value==0
                            plotStripe(g,5)=errorbar(app.UIAxes3_2,app.Xfocus{g}(gr),abs(app.MSet{g}(gr)),app.Std_neg{g}(gr),app.Std_pos{g}(gr),'.','LineStyle','none','Color',[1 0 0]);
                        end
                        plotStripe(g,6)=plot(app.UIAxes3_2,app.Xfocus{g}(gr),abs(app.MSet{g}(gr)),'o','LineStyle','none','MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 0 0],'MarkerSize',2,'Tag',num2str(g));
                    end

                    %% Black
                    if ~isempty(gb)
                        if app.HideerrorbarsCheckBox.Value==0
                            plotStripe(g,8)=errorbar(app.UIAxes3_2,app.Xfocus{g}(gb),abs(app.MSet{g}(gb)),app.Std_neg{g}(gb),app.Std_pos{g}(gb),'.','LineStyle','none','Color','#1F1F1F');
                        end
                        plotStripe(g,9)=plot(app.UIAxes3_2,app.Xfocus{g}(gb),abs(app.MSet{g}(gb)),'o','LineStyle','none','MarkerFaceColor','#1F1F1F','MarkerEdgeColor','#1F1F1F','MarkerSize',2,'Tag',num2str(g));
                    end

                end

                app.Next.Enable = 'on';
                app.Previous.Enable = 'on';

                if app.UIAxes3_2.XLim(1,1)<=min(app.DIST(:,2))
                    app.Previous.Enable = 'off';
                end
                if app.UIAxes3_2.XLim(1,2)>=max(app.DIST(:,2))
                    app.Next.Enable = 'off';
                end

            end
        end


        function PlotPatchwork(app)

            if app.Frame==3
                cla(app.UIAxes3_3)

                selectedNodes = app.Tree.SelectedNodes;

                [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

                hSet=height(app.SetPdepthSide{m,n});

                if isequal(app.TypeDropDown_patchwork.Value,'M (data)')
                    MPOL=app.MPOL;

                elseif isequal(app.TypeDropDown_patchwork.Value,'M (forward)')
                    MPOL=app.fMPOL;

                elseif isequal(app.TypeDropDown_patchwork.Value,'M (misfit)')
                    MPOL=app.mMPOL;

                end


                if isempty(app.MSet{1})
                    for g=1:app.NGates-1
                        for h=1:hSet
                            app.MSet{g}(h)=MPOL(app.SetPdepthSide{m,n}(h,1),25+app.NGates*2+g);
                            app.Xfocus{g}(h)=app.SetPdepthSide{m,n}(h,2);
                        end
                    end
                end


                %% Plot
                for g=1:app.NGates-1

                    for h=1:hSet
                        Flags{g}(h)=app.FLAGS(app.SetPdepthSide{m,n}(h,1),g+2,10);
                    end

                    ggGate=[];
                    gg=find(Flags{g}==1);
                    ggGate(1:length(gg)) = g;

                    gcGate=[];
                    gc=find(Flags{g}==0 & app.MSet{g}~=0);
                    gcGate(1:length(gc)) = g;

                    gzGate=[];
                    gz=find(Flags{g}==0 & app.MSet{g}==0);
                    gzGate(1:length(gz)) = g;


                    if app.HiderejectedpointsCheckBox.Value==0
                        scatter(app.UIAxes3_3,gg,ggGate,70,[0.7 0.7 0.7],'filled','s');
                    end
                    scatter(app.UIAxes3_3,gz,gzGate,60,'s','MarkerEdgeColor',[0 0 0], 'LineWidth',1);


                    if isequal(app.TypeDropDown_patchwork.Value,'M (data)') || isequal(app.TypeDropDown_patchwork.Value,'M (forward)')
                        gcGate=[];
                        gc=find(Flags{g}==0 & app.MSet{g}~=0);
                        gcGate(1:length(gc)) = g;

                    elseif isequal(app.TypeDropDown_patchwork.Value,'M (misfit)')
                        gcGate=[];
                        gc=find(Flags{g}==0 & app.MSet{g}~=0 & app.MSet{g}~=9999);
                        gcGate(1:length(gc)) = g;

                        gxGate=[];
                        gx=find(Flags{g}==0 & app.MSet{g}==9999);
                        gxGate(1:length(gx)) = g;

                        scatter(app.UIAxes3_3,gx,gxGate,60,'x','MarkerEdgeColor',[0 0 0], 'LineWidth',0.7);
                    end

                    color=app.MSet{g}(gc);
                    scatter(app.UIAxes3_3,gc,gcGate,70,color,'filled','s');

                end

                for i=1:2:length(app.Xfocus{1})
                    t=text(app.UIAxes3_3,i,app.NGates+4,num2str(round(app.Xfocus{1}(i),1,'decimals')));
                    t.Rotation = 90;
                    t.FontSize = 10;
                end

            end

        end


        %%%%%%  FUNCTIONS TO IDENTIFY SELECTED MEASUREMENTS %%%%%%%

        % IDENTIFY THE QUADRUPOLE FROM THE SELECTED POINTS (row in qui file) TO PLOT THE DECAYS:
        % first search in data profiles, if there aren't selected points search in
        % Pseudosection, then in Positions and finally in Data Stripe,
        % until a selection is found
        % OUTPUT: rowFLAGSxDecays = array with the number of the rows in MPOL of
        % the multipoles selected

        function FindSelectedRow(app)
            %% Find row in MPOL

            app.rowFLAGSxDecays=int16.empty;

            %% Find row of brushed points in Data Profiles (if present)
            app.Graph='prof';
            if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'intM (data)')
                MPOL=app.MPOL;
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_profile.Value,'intM (forward)')
                MPOL=app.fMPOL;
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                MPOL=app.mMPOL;
            end
            for l=1:length(app.UIAxes2.Children)
                d=find(app.UIAxes2.Children(l).BrushData==1);
                if ~isempty(d)


                    for i=1:length(d)
                        XData=app.UIAxes2.Children(l).XData(d(i));
                        YData=app.UIAxes2.Children(l).YData(d(i));
                        row=find(MPOL(:,12)==XData & abs(app.MPOLRhoaShift(:,1))==YData);
                        app.rowFLAGSxDecays=[app.rowFLAGSxDecays; row];
                        app.rowFLAGSxDecays=unique(app.rowFLAGSxDecays);
                    end
                end
            end

            if isempty(app.rowFLAGSxDecays)
                %% Find row of brushed points in Pseudosection (if present)
                app.Graph='pseu';
                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (data)')
                    MPOL=app.MPOL;
                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)')
                    MPOL=app.fMPOL;
                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    MPOL=app.mMPOL;
                end
                for l=1:length(app.UIAxes3.Children)
                    d=find(app.UIAxes3.Children(l).BrushData==1);
                    if ~isempty(d)

                        for i=1:length(d)
                            XData=app.UIAxes3.Children(l).XData(d(i));
                            YData=app.UIAxes3.Children(l).YData(d(i));
                            row=find(MPOL(:,12)==XData & MPOL(:,14)==YData);
                            app.rowFLAGSxDecays=[app.rowFLAGSxDecays; row];
                            app.rowFLAGSxDecays=unique(app.rowFLAGSxDecays);
                        end
                    end
                end
            end

            if isempty(app.rowFLAGSxDecays)
                %% Find row of brushed points in Positions (if present)
                app.Graph='posi';
                MPOL=app.MPOL;
                for l=1:length(app.UIAxes.Children)
                    d=find(app.UIAxes.Children(1).BrushData==1);
                    if ~isempty(d)
                        for i=1:length(d)
                            rowA=find(MPOL(:,5)==d(i));       %%%%%% consider all the electrodes ABMN
                            rowB=find(MPOL(:,7)==d(i));
                            rowM=find(MPOL(:,10)==d(i));
                            rowN=find(MPOL(:,11)==d(i));
                            app.rowFLAGSxDecays=[app.rowFLAGSxDecays; rowA; rowB; rowM; rowN];
                            app.rowFLAGSxDecays=unique(app.rowFLAGSxDecays);
                        end
                    end
                end
            end

            if isempty(app.rowFLAGSxDecays)
                MPOL=app.MPOL;
                if ~isempty(app.Tree.SelectedNodes)
                    selectedNodes = app.Tree.SelectedNodes;

                    [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                    hSet=height(app.SetPdepthSide{m,n});
                    %% Find row of brushed points in Stripe (if present)
                    app.Graph='strp';
                    for  l2=1:length(app.UIAxes3_2.Children)
                        if app.UIAxes3_2.Children(l2).Marker=='o'

                            d2=find(app.UIAxes3_2.Children(l2).BrushData==1);
                            for i=1:length(d2)
                                XData=app.UIAxes3_2.Children(l2).XData(d2(i));
                                row=find(app.SetPdepthSide{m,n}(:,2)==XData);
                                app.rowFLAGSxDecays=[app.rowFLAGSxDecays; app.SetPdepthSide{m,n}(row,1)];
                                app.rowFLAGSxDecays=unique(app.rowFLAGSxDecays);
                            end
                        end
                    end
                end
            end

            if isempty(app.rowFLAGSxDecays)

                if ~isempty(app.Tree.SelectedNodes)
                    if isequal(app.TypeDropDown_patchwork.Value,'M (data)')
                        MPOL=app.MPOL;
                    elseif isequal(app.TypeDropDown_patchwork.Value,'M (forward)')
                        MPOL=app.fMPOL;
                    elseif isequal(app.TypeDropDown_patchwork.Value,'M (misfit)')
                        MPOL=app.mMPOL;
                    end

                    selectedNodes = app.Tree.SelectedNodes;

                    [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                    hSet=height(app.SetPdepthSide{m,n});
                    nText=ceil(hSet/2);
                    %% Find row of brushed points in Patchwork (if present)
                    app.Graph='patc';
                    for  l2=nText+1:length(app.UIAxes3_3.Children)
                        d2=find(app.UIAxes3_3.Children(l2).BrushData==1);
                        for i=1:length(d2)
                            row=app.UIAxes3_3.Children(l2).XData(d2(i));
                            app.rowFLAGSxDecays=[app.rowFLAGSxDecays; app.SetPdepthSide{m,n}(row,1)];
                            app.rowFLAGSxDecays=unique(app.rowFLAGSxDecays);
                        end

                    end
                end
            end
            if isempty(app.rowFLAGS)
                app.Graph='none';
            end
        end


        % IDENTIFY THE QUADRUPOLE FROM THE SELECTED POINTS (row in qui file) TO CHANGE FLAG (RES - INT M):
        % first search in data profiles, if there aren't selected points search in
        % Pseudosection, until a selection is found
        % OUTPUT: rowFLAGSxRhoa = array with the number of the rows in MPOL of
        % the multipoles selected

        function FindSelectedRhoa(app)

            app.rowFLAGS=int16.empty;
            app.colFLAGS=cell(height(app.MPOL),1);
            app.rowFLAGSxRhoa=int16.empty;

            %% Find row of brushed points in Profiles
            app.Graph='prof';
            if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'intM (data)')
                MPOL=app.MPOL;
                app.Type='data';
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_profile.Value,'intM (forward)')
                MPOL=app.fMPOL;
                app.Type='forw';
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                MPOL=app.mMPOL;
                app.Type='misf';
            end

            for l=1:length(app.UIAxes2.Children)

                d=find(app.UIAxes2.Children(l).BrushData==1);
                if ~isempty(d)

                    for i=1:length(d)
                        XData=app.UIAxes2.Children(l).XData(d(i));
                        YData=app.UIAxes2.Children(l).YData(d(i));
                        row=find(MPOL(:,12)==XData & abs(app.MPOLRhoaShift(:,1))==YData);
                        app.rowFLAGSxRhoa=[app.rowFLAGSxRhoa; row];
                        app.rowFLAGSxRhoa=unique(app.rowFLAGSxRhoa);

                    end
                end
                if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)')
                    app.Parameter='Rhoa';
                elseif isequal(app.TypeDropDown_profile.Value,'intM (data)') || isequal(app.TypeDropDown_profile.Value,'intM (forward)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                    app.Parameter='intM';
                end
            end


            %% Find row of brushed points in Pseudosection

            if isempty(app.rowFLAGSxRhoa)
                app.Graph='pseu';
                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (data)')
                    MPOL=app.MPOL;
                    app.Type='data';
                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)')
                    MPOL=app.fMPOL;
                    app.Type='forw';
                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    MPOL=app.mMPOL;
                    app.Type='misf';
                end
                for l=1:length(app.UIAxes3.Children)
                    d=find(app.UIAxes3.Children(l).BrushData==1);
                    if ~isempty(d)

                        for i=1:length(d)
                            XData=app.UIAxes3.Children(l).XData(d(i));
                            YData=app.UIAxes3.Children(l).YData(d(i));
                            row=find(MPOL(:,12)==XData & MPOL(:,14)==YData);
                            app.rowFLAGSxRhoa=[app.rowFLAGSxRhoa; row];
                            app.rowFLAGSxRhoa=unique(app.rowFLAGSxRhoa);

                        end

                    end
                end
                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)')
                    app.Parameter='Rhoa';
                elseif isequal(app.TypeDropDown_pseudosection.Value,'intM (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    app.Parameter='intM';
                end
            end
            if isempty(app.rowFLAGS)
                app.Graph='none';
            end


        end


        % IDENTIFY THE QUADRUPOLE FROM THE SELECTED POINTS (row in qui file) TO CHANGE THE FLAG (M - GATES):
        % first search in decays, then in Positions in Data Stripe, until a selection is found
        % OUTPUT:
        %   - rowFLAGS = array with the number of the rows in MPOL of the multipoles selected
        %   - colFLAGS = cell array; at the rows in rowFLAGS are stored the
        %   gates selected for that quadrupole


        function FindSelectedGates(app)
            %% Find Gate in MPOL (Gate numbers). ATTENCTION: row number
            % in colFLAGS (cells) correspond with row number in MPOL.


            app.rowFLAGS=int16.empty;
            app.colFLAGS=cell(height(app.MPOL),1);
            app.rowFLAGSxRhoa=int16.empty;


            %% Find row and col of brushed points in Decays (if present)
            app.Graph='deca';
            for l2=1:length(app.UIAxes4.Children)
                if app.UIAxes4.Children(l2).Marker=='o'
                    d2=find(app.UIAxes4.Children(l2).BrushData==1);
                    for i=1:length(d2)
                        row=str2num(app.UIAxes4.Children(l2).Tag);
                        app.rowFLAGS=[app.rowFLAGS; row];
                        app.rowFLAGS=unique(app.rowFLAGS);
                        XData=app.UIAxes4.Children(l2).XData(d2(i));
                        NGateXData=find(app.GATES(:,3)==XData);
                        app.colFLAGS{row,1}=[app.colFLAGS{row,1} NGateXData+2];
                        app.colFLAGS{row,1}=unique(app.colFLAGS{row,1});
                    end
                end
            end


            if isempty(app.rowFLAGS)
                selectedNodes = app.Tree.SelectedNodes;
                app.SetPdepthSide=app.SetPdepthSide;
                [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                hSet=height(app.SetPdepthSide{m,n});

                %% Find row and col of brushed points in Stripe (if present)
                app.Graph='strp';
                for  l2=1:length(app.UIAxes3_2.Children)
                    if app.UIAxes3_2.Children(l2).Marker=='o'

                        d2=find(app.UIAxes3_2.Children(l2).BrushData==1);
                        for i=1:length(d2)
                            XData=app.UIAxes3_2.Children(l2).XData(d2(i));
                            %                             YData=app.UIAxes3_2.Children(l2).YData(d2(i));

                            row=find(app.SetPdepthSide{m,n}(:,2)==XData);
                            app.rowFLAGS=[app.rowFLAGS; app.SetPdepthSide{m,n}(row,1)];
                            app.rowFLAGS=unique(app.rowFLAGS);

                            app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1}=[app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1} str2num(app.UIAxes3_2.Children(l2).Tag)+2];
                            app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1}=unique(app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1});
                        end
                    end
                end
            end

            if isempty(app.rowFLAGS)
                if ~isempty(app.Tree.SelectedNodes)
                    selectedNodes = app.Tree.SelectedNodes;

                    [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                    hSet=height(app.SetPdepthSide{m,n});
                    nText=ceil(hSet/2);
                    %% Find row of brushed points in Patchwork (if present)
                    app.Graph='patc';
                    if isequal(app.TypeDropDown_patchwork.Value,'M (data)')
                        app.Type='data';
                    elseif isequal(app.TypeDropDown_patchwork.Value,'M (forward)')
                        app.Type='forw';
                    elseif isequal(app.TypeDropDown_patchwork.Value,'M (misfit)')
                        app.Type='misf';
                    end

                    for  l2=nText+1:length(app.UIAxes3_3.Children)

                        d2=find(app.UIAxes3_3.Children(l2).BrushData==1);
                        for i=1:length(d2)
                            row=app.UIAxes3_3.Children(l2).XData(d2(i));
                            YData=app.UIAxes3_3.Children(l2).YData(d2(i));

                            app.rowFLAGS=[app.rowFLAGS; app.SetPdepthSide{m,n}(row,1)];
                            app.rowFLAGS=unique(app.rowFLAGS);

                            app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1}=[app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1} YData+2];
                            app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1}=unique(app.colFLAGS{app.SetPdepthSide{m,n}(row,1),1});
                        end

                    end


                end
            end
            if isempty(app.rowFLAGS)
                app.Graph='none';
            end


        end


        % TO SHOW IN POSITIONS, DATA PROFILES, PSEUDOSECTION AND IN GIS MAP
        % THE MEASUREMENTS THAT ARE SHOWN IN DECAYS.
        %Shownpositions, Shownprofile, ShownPseudosec are plots that
        %superimpose the plot of all the data and are deleted and recreated
        %when a new selection is made
        % Q is sent to gisapp with only the coordinates of the selected
        % electrodes

        function Shown(app)

            if ~isempty(app.ShownPositions)
                delete (app.ShownPositions);
            end
            if ~isempty(app.ShownProfile)
                delete (app.ShownProfile);
            end
            if ~isempty(app.ShownPseudosec)
                delete (app.ShownPseudosec);
            end

            if isempty(app.rowFLAGSxDecays)
                app.FindSelectedRow;
            end

            %%%Positions
            Q = [];
            Cur = [];
            Pot = [];
            app.ShownPositions=gobjects(0);
            for r=1:height(app.rowFLAGSxDecays)
                A=app.MPOL(app.rowFLAGSxDecays(r),5);
                B=app.MPOL(app.rowFLAGSxDecays(r),7);
                N=app.MPOL(app.rowFLAGSxDecays(r),10);
                M=app.MPOL(app.rowFLAGSxDecays(r),11);

                Cur = [Cur A B];
                Pot = [Pot M N];
                %                 Q{r,1}=[A B M N];
            end

            Q{1} = intersect(Cur,Pot); % Both
            Q{2} = setdiff(Cur,Pot);   % Only Curren
            Q{3} = setdiff(Pot,Cur);   % Only Potential

            app.ShownPositions=[app.ShownPositions plot(app.UIAxes,app.DIST(Q{1},2),app.TAB{4}(Q{1},5),'o','MarkerEdgeColor','#bb00bb','MarkerFaceColor','#bb00bb','MarkerSize',3)];
            app.ShownPositions=[app.ShownPositions plot(app.UIAxes,app.DIST(Q{2},2),app.TAB{4}(Q{2},5),'o','MarkerEdgeColor',[1 0 0],'MarkerFaceColor',[1 0 0],'MarkerSize',3)];
            app.ShownPositions=[app.ShownPositions plot(app.UIAxes,app.DIST(Q{3},2),app.TAB{4}(Q{3},5),'o','MarkerEdgeColor',[0 0 1],'MarkerFaceColor',[0 0 1],'MarkerSize',3)];

            if app.Frame==1

                %%% Profile
                if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'intM (data)')
                    MPOL=app.MPOL;
                elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_profile.Value,'intM (forward)')
                    MPOL=app.fMPOL;
                elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                    MPOL=app.mMPOL;
                end

                if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)')
                    ind=1;
                elseif isequal(app.TypeDropDown_profile.Value,'intM (data)') || isequal(app.TypeDropDown_profile.Value,'intM (forward)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                    ind=2;
                end

                app.ShownProfile=gobjects(0);
                for i=1:height(app.rowFLAGSxDecays)
                    if ismember(app.rowFLAGSxDecays(i),app.ROWSChecked)
                        if ~(app.FLAGS(app.rowFLAGSxDecays(i),ind,app.kMid)==1 && app.HiderejectedpointsCheckBox.Value==1)
                            app.ShownProfile(i)=plot(app.UIAxes2,MPOL(app.rowFLAGSxDecays(i),12),app.MPOLRhoaShift(app.rowFLAGSxDecays(i),1),'o','MarkerEdgeColor',[1 1 1],'LineWidth',1,'MarkerFaceColor','#bb00bb','MarkerSize',4);
                        end
                    end
                end

                %%%Pseudosection

                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (data)')
                    MPOL=app.MPOL;
                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)')
                    MPOL=app.fMPOL;
                elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    MPOL=app.mMPOL;
                end

                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)')
                    ind=1;
                elseif isequal(app.TypeDropDown_pseudosection.Value,'intM (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    ind=2;
                end

                app.ShownPseudosec=gobjects(0);
                for i=1:height(app.rowFLAGSxDecays)
                    if ~(app.FLAGS(app.rowFLAGSxDecays(i),ind,app.kMid)==1 && app.HiderejectedpointsCheckBox.Value==1)
                        app.ShownPseudosec(i)=plot(app.UIAxes3,app.MPOL(app.rowFLAGSxDecays(i),12),MPOL(app.rowFLAGSxDecays(i),14),'o','MarkerEdgeColor',[1 1 1],'LineWidth',1,'MarkerFaceColor','#bb00bb','MarkerSize',4);
                    end
                end
            end

            importdata_processingapp(app.callingapp,Q,app.TAB);
        end

        function CalculatePdepth(app)

            cnfig=app.ABMN;

            frac=0.5;
            [Nconfigs ~]=size(cnfig);      % M:number of configurations, ~:number of electrodes
            zmax=4*max(abs(cnfig(:,1)-cnfig(:,2))) ;         % depth vector
            dz=0.25*min(abs(cnfig(:,4)-cnfig(:,3)));         % depth vector
            z=0:0.1:zmax;
            %preallocate arrays
            BM_full = abs(cnfig(:,2) - cnfig(:,3));    %BM
            BN_full = abs(cnfig(:,2) - cnfig(:,4));    %BN
            AM_full = abs(cnfig(:,1) - cnfig(:,3));    %AM
            AN_full = abs(cnfig(:,1) - cnfig(:,4));    %AN

            %     Gi = fri1dpp_full(z,BM_full,BN_full,AM_full,AN_full);

            % ------------------------------------------------

            [C,ia,ic] = unique([BM_full BN_full AM_full AN_full],'rows','stable');
            zcol = full(4*z(:).^2);   % Make sure y is a full column vector.
            bmrow = full(C(:,1)).'; % Make sure x is a full row vector.
            bnrow = full(C(:,2)).'; % Make sure x is a full row vector.
            amrow = full(C(:,3)).'; % Make sure x is a full row vector.
            anrow = full(C(:,4)).'; % Make sure x is a full row vector.

            BM = repmat(bmrow,size(zcol));
            BN = repmat(bnrow,size(zcol));
            AM = repmat(amrow,size(zcol));
            AN = repmat(anrow,size(zcol));
            Zed = repmat(zcol,size(bmrow));

            y1 = sqrt(1./(Zed./BM.^2+1));
            y2 = sqrt(1./(Zed./BN.^2+1));
            y3 = sqrt(1./(Zed./AM./AM+1));
            y4 = sqrt(1./(Zed./AN./AN+1));

            g = (1 - y1)./(2*pi.*BM)-(1 - y2)./(2*pi.*BN)-(1 - y3)./(2*pi.*AM)+(1 - y4)./(2*pi.*AN);
            Gi = g(:,ic);

            % ------------------------------------------------

            [Nz,Nconf]=size(Gi);
            gf = 2*pi./(1./BM_full - 1./BN_full - 1./AM_full + 1./AN_full);  %geometric factor
            Gi=(ones(Nz,1)*gf').*Gi;

            %find focus depth
            Pdepth=zeros(1,Nconfigs);
            for k=1:Nconfigs
                I=find(Gi(:,k)<=frac);
                n=length(I);
                Pdepth(k)=z(n);
            end
            Pdepth=round(Pdepth)';

            app.calcPdepth=Pdepth;

        end

        function CalculateXfocus(app)

            elpos=app.ABMN;

            n=1.75;
            d_pol(:,1)=elpos(:,3)-elpos(:,1);
            d_pol(:,2)=elpos(:,4)-elpos(:,1);
            d_pol(:,3)=elpos(:,3)-elpos(:,2);
            d_pol(:,4)=elpos(:,4)-elpos(:,2);
            d_pol(:,5)=elpos(:,1)-elpos(:,2);
            d_pol(:,6)=elpos(:,3)-elpos(:,4);
            d_pol=abs(d_pol);

            m_pol(:,1)=elpos(:,3)+elpos(:,1);
            m_pol(:,2)=elpos(:,4)+elpos(:,1);
            m_pol(:,3)=elpos(:,3)+elpos(:,2);
            m_pol(:,4)=elpos(:,4)+elpos(:,2);
            m_pol(:,5)=elpos(:,1)+elpos(:,2);
            m_pol(:,6)=elpos(:,3)+elpos(:,4);
            m_pol=m_pol/2;

            apx=m_pol./(d_pol).^n;
            rp=1./(d_pol).^n;
            rp=sum(rp,2);
            Xfocus=sum(apx,2)*1./rp;

            app.calcXfocus=Xfocus;

        end


        function UploadForward(app)

            [filenameForward,pathnameForward]=uigetfile('*.qu*');
            addpath(pathnameForward);
            app.ForwardFile=[pathnameForward filenameForward];
            fileID=fopen(app.ForwardFile);
            filescan=textscan(fileID,'%s','delimiter','\n','whitespace',' ');

            %% sections from .qui file
            %%fTAB{1}-fTAB{10}
            ROW=zeros(1,11);
            i=0;
            for i=1:11
                row_i=strfind(filescan{1}, append('%',num2str(i)));
                row_i=find(~cellfun('isempty',row_i),1);

                ROW(i)=row_i;
            end          % ROW (vector) contains the number of the row of each section


            %fTAB{11}:fMPOL
            rows_filescan=height(filescan{1});  %Computes number of rows in filescan

            cols_MPOL=0;
            rows_MPOL=0;
            for m=ROW(1,11)+1:rows_filescan
                [A,COUNT,ERRMSG,NEXTINDEX] = sscanf(filescan{1}{m,1},'%e');
                if COUNT>0
                    cols_MPOL=max(cols_MPOL,COUNT);
                    rows_MPOL=rows_MPOL+1;
                end

            end     % Counts Col and Rows that will be in MPOL

            fMPOL=zeros(rows_MPOL,cols_MPOL);

            k=0;
            for m=ROW(1,11)+1:rows_filescan
                [A,COUNT,ERRMSG,NEXTINDEX] = sscanf(filescan{1}{m,1},'%e');
                if COUNT>0
                    k=k+1;
                    fMPOL(k,:)=A';
                end

            end                     % Create MPOL matrix and rawdata cell array

            if ~isequal(sortrows(app.MPOL(:,[5 7 9 10])),sortrows(fMPOL(:,[5 7 9 10])))
                err=uiconfirm(app.UIFigure,'Observed data and forward files are different. The two files must be equal exept for the values of rhoa and M.', 'Upload forward','Icon','warning','Options',{'OK'});
            else

                %% Create Tags in Dropdown Menus
                if width(app.MPOL)>25
                    app.TypeDropDown_pseudosection.Items = {'Rhoa (data)', 'intM (data)', 'Rhoa (forward)', 'intM (forward)', 'Rhoa (misfit)', 'intM (misfit)'};
                    app.TypeDropDown_profile.Items = {'Rhoa (data)', 'intM (data)', 'Rhoa (forward)', 'intM (forward)', 'Rhoa (misfit)', 'intM (misfit)'};

                    app.TypeDropDown_decays.Items = {'M (data)', 'M (data + forward)'};
                    app.TypeDropDown_stripe.Items = {'M (data)', 'M (data + forward)'};
                    app.TypeDropDown_patchwork.Items = {'M (data)', 'M (forward)', 'M (misfit)'};

                else
                    app.TypeDropDown_pseudosection.Items = {'Rhoa (data)', 'Rhoa (forward)', 'Rhoa (misfit)'};
                    app.TypeDropDown_profile.Items = {'Rhoa (data)', 'Rhoa (forward)',  'Rhoa (misfit)'};

                end
                app.ForwardEditField.Value=filenameForward;

                if width(app.MPOL)>25
                    %% Calculate integral chargeability
                    GateWidth=app.GATES(:,2)';
                    tTOT=sum(GateWidth);
                    for j=1:height(fMPOL)
                        M=fMPOL(j,26+app.NGates*2:24+app.NGates*3);
                        num=0;
                        for i=1:app.NGates-1
                            num=num+(M(i)*GateWidth(i));
                        end

                        intM=num/tTOT;
                        fMPOL(j,25+app.NGates*2)=intM;
                    end
                end


                for i=1:3
                    for j=1:height(app.SetPdepthSide)
                        if ~isempty(app.SetPdepthSide{j,i})

                            for k=1:height(app.SetPdepthSide{j,i})
                                fSetPdepthSide{j,i}(:,[1:3 6])=app.SetPdepthSide{j,i}(:,[1:3 6]);
                                mSetPdepthSide{j,i}(:,[1:3 6])=app.SetPdepthSide{j,i}(:,[1:3 6]);

                                RES=fMPOL(fSetPdepthSide{j,i}(k,1),22+app.NGates*2);
                                fSetPdepthSide{j,i}(k,4)=RES;
                                if RES~=0
                                    mSetPdepthSide{j,i}(k,4)=app.SetPdepthSide{j,i}(k,4)/RES;
                                else
                                    mSetPdepthSide{j,i}(k,4)=9999;
                                end
                                if width(app.MPOL)>25
                                    INTM=fMPOL(fSetPdepthSide{j,i}(k,1),25+app.NGates*2);
                                    fSetPdepthSide{j,i}(k,5)=INTM;
                                    if INTM~=0
                                        mSetPdepthSide{j,i}(k,5)=app.SetPdepthSide{j,i}(k,5)/INTM;
                                    else
                                        mSetPdepthSide{j,i}(k,5)=9999;
                                    end
                                end
                            end
                        end
                    end
                end

                for i=1:height(app.MPOL)
                    for j=22+app.NGates*2:24+app.NGates*3

                        if fMPOL(i,j)~=0
                            mMPOL(i,j)=app.MPOL(i,j)/fMPOL(i,j);
                        else
                            mMPOL(i,j)=9999;
                        end
                    end
                end

                fMPOL(:,[12 14])=app.MPOL(:,[12 14]);
                mMPOL(:,[12 14])=app.MPOL(:,[12 14]);
                

                app.fMPOL=fMPOL;
                app.mMPOL=mMPOL;
                app.fSetPdepthSide=fSetPdepthSide;
                app.mSetPdepthSide=mSetPdepthSide;


                %% Update plots: Profiles

                if isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  | isequal(app.TypeDropDown_profile.Value,'intM (forward)') | isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') | isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                    app.PlotDataProfile;
                    app.fixAxisProfiles;
                end


                %% Pseudosection
                if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)')  | isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)') | isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)') | isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                    app.PlotPseudosection;
                end

                %% Decays
                if width(app.MPOL)>25
                    if isequal(app.TypeDropDown_decays.Value,'M (data + forward)')
                        app.PlotDecay;
                    end

                    %% Stripe
                    if ~isempty(app.Tree.SelectedNodes) && isequal(app.TypeDropDown_stripe.Value,'M (data)', 'M (data + forward)')
                        app.PlotDataStripe;
                    end
                end

                app.Shown;

                updated=uiconfirm(app.UIFigure,'Uploaded', 'Upload forward file','Icon','success','Options',{'OK'});
            end
        end


        function fixAxisProfiles(app)

            if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')
                app.UIAxes2.YScale='log';
                app.settingsProfile{1}(2,:)=[min(app.MPOLRhoaShift)-10 max(app.MPOLRhoaShift)+10];
                if app.CurveshiftfactorSpinner.Value>1
                    ylabel(app.UIAxes2, 'Rhoa [-]')
                else
                    ylabel(app.UIAxes2, 'Rhoa [ohm m]')
                end

            elseif isequal(app.TypeDropDown_profile.Value,'intM (data)') || isequal(app.TypeDropDown_profile.Value,'intM (forward)')
                app.UIAxes2.YScale='log';
                app.settingsProfile{1}(2,:)=[min(app.MPOLRhoaShift) max(app.MPOLRhoaShift)];
                if app.CurveshiftfactorSpinner.Value>1
                    ylabel(app.UIAxes2, 'intM [-]')
                else
                    ylabel(app.UIAxes2, 'intM [mV/V]')
                end

            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                app.UIAxes2.YScale='lin';
                app.settingsProfile{1}(2,:)=[-10 10];
                if app.CurveshiftfactorSpinner.Value>1
                    ylabel(app.UIAxes2, 'd obs/forw [-]')
                else
                    ylabel(app.UIAxes2, 'd obs/forw [-]')
                end
            end

            app.UIAxes2.YLim=app.settingsProfile{1}(2,:);
            app.settingsProfile{2}= app.settingsProfile{1};
        end

        function fixAxisPseudosection(app)

            if isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (data)') || isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (forward)')
                %                 colorscaleLim=log10([app.MinRhoa app.MaxRhoa]);
                colorscaleLim=[app.MinRhoa10 app.MaxRhoa90];
                colormap (app.UIAxes3,jet);
                caxis(app.UIAxes3,colorscaleLim);
                c=colorbar(app.UIAxes3,'southoutside');
                %                 c.Label.String = 'log(|Rhoa|) [log(ohm m)]';
                c.Label.String = 'Rhoa [ohm m]';
                c.Ruler.Scale = 'log';
                c.Ruler.MinorTick = 'on';
                c.TickDirection = 'out';
                c.Label.FontSize = 7;

            elseif isequal(app.TypeDropDown_pseudosection.Value,'intM (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)')
                %                 colorscaleLim=log10([app.MinM app.MaxM]);
                colorscaleLim=[app.MinIntM10 app.MaxIntM90];
                colormap (app.UIAxes3,jet);
                caxis(app.UIAxes3,colorscaleLim);
                c=colorbar(app.UIAxes3,'southoutside');
                %                 c.Label.String = 'log(|intM|) [log(mV/V)]';
                c.Label.String = 'intM [mV/V]';
                c.Ruler.Scale = 'log';
                c.Ruler.MinorTick = 'on';
                c.TickDirection = 'out';
                c.Label.FontSize = 7;

            elseif isequal(app.TypeDropDown_pseudosection.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')
                colorscaleLim=[-10 10];
                colormap (app.UIAxes3,app.MisfitCM);
                caxis(app.UIAxes3,colorscaleLim);
                c=colorbar(app.UIAxes3,'southoutside');
                c.Label.String = 'd obs/forw [-]';
                c.Label.FontSize = 7;
            end
        end

        function fixAxisPatchwork(app)

            if isequal(app.TypeDropDown_patchwork.Value,'M (data)') || isequal(app.TypeDropDown_patchwork.Value,'M (forward)')
                a=app.MaxAsinhM/192*64;
                colorscaleLim=[-a app.MaxAsinhM];
                colormap (app.UIAxes3_3,app.MCM);
                caxis(app.UIAxes3_3,colorscaleLim);
                c=colorbar(app.UIAxes3_3,'southoutside');
                c.Label.String = 'M [mV/V]';
                c.TickDirection = 'out';
                c.Label.FontSize = 7;

            elseif isequal(app.TypeDropDown_patchwork.Value,'M (misfit)')

                colorscaleLim=[-10 10];
                colormap (app.UIAxes3_3,app.MisfitCM);
                caxis(app.UIAxes3_3,colorscaleLim);
                c=colorbar(app.UIAxes3_3,'southoutside');
                c.Label.String = 'd obs/forw [-]';
                c.TickDirection = 'out';
                c.Label.FontSize = 7;

            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gisapp, TAB, MPOL, RAWDATA, DIST, QuiFile)

            app.callingapp=gisapp;

            app.UIFigure.Name = QuiFile; % The name of the qui file is displayed in the window name
            app.UIFigure.Icon = 'LOGO-provvisorio.png';

            %% ------------------------------------------------------------------------------
            %% ADAPT APP IF THERE IS ONLY RESISTIVITY INFORMATION IN THE .qui/.quo FILE,
            %  NOT INTEGRAL CHARGEABILITY

            if width(MPOL)>25
                app.NGates=TAB{7}(height(TAB{7}),1);
            else
                app.NGates=0;
            end

            %% Disable handles for Decays, Stripe and Patchwork plots (no M data)
            if width(MPOL)==25
                app.SettingsButton_decay.Enable = 'off';
                app.SettingsButton_stripe.Enable = 'off';
                app.SettingsButton_patchwork.Enable = 'off';
                app.TypeDropDown_2Label.FontColor = '[0.65,0.65,0.65]';
                app.TypeDropDown_decays.Enable = 'off';
                app.TypeDropDown_3Label.FontColor = '[0.65,0.65,0.65]';
                app.TypeDropDown_stripe.Enable = 'off';
                app.TypeDropDown_3Label_2.FontColor = '[0.65,0.65,0.65]';
                app.TypeDropDown_patchwork.Enable = 'off';
                app.TypeDropDown_pseudosection.Items = {'Rhoa (data)'};
                app.TypeDropDown_profile.Items = {'Rhoa (data)'};
                app.UpdateintMvaluesButton.Enable = 'off';
                app.HideerrorbarsCheckBox.Enable = 'off';
                app.ColorbarEditorButton_2.Enable = 'off';
            end
            %% --------------------------------------------------------------------------------

            app.Frame=1;        % First tab is on the front, Data Profiles
            % and Pseudosection will be active

            %% --------------------------------------------------------------------------------
            %% MANAGE VARIABLES CONTAINING MEASUREMENTS INFORMATION

            %% Calculate Pdepth and Xfocus
            app.ABMN=[MPOL(:,5) MPOL(:,7) MPOL(:,10) MPOL(:,11)];

            % Now ABMN are the number of electrodes
            for i=1:height(app.ABMN)
                for j=1:width(app.ABMN)
                    f=find(DIST(:,1)==app.ABMN(i,j));
                    app.ABMN(i,j)=DIST(f,2);
                end
            end
            % Now ABMN is expressed in distance of the electrode from the start of the line

            %% Calculate and replace in MPOL lateral focus and pseudodepth
            app.CalculateXfocus;
            app.CalculatePdepth;
            MPOL(:,12)= app.calcXfocus;
            MPOL(:,14)= app.calcPdepth;


            %% ------------------------------------------------------------------------
            %% MANAGE FLAGS FOR COMMENTING DATA
            %% FLAGS is a 3-D array
            %% First 2 dim. containing flags (0/1)
            %% Third dimension are repetitions of the 2-D flags arrays:
            %% - the one to read and update is the kMid,
            %% - the ones before are precedent versions at each modification for 'undo' (Ctrl+Z),
            %% - the ones from kMid+1 to kEnd for 're-do' (Ctrl+Y)

            app.kEnd=20;
            app.kMid=10;

            if width(MPOL)==25
                %% FLAGS if we have only RES
                app.FLAGS=zeros(height(MPOL),1,app.kEnd);
                app.FLAGS(:,1,:)=NaN;
                app.FLAGS(:,1,app.kMid)=MPOL(:,19);
            end

            if width(MPOL)>25
                %% FLAGS if we have RES and IP
                app.FLAGS=zeros(height(MPOL),app.NGates+1,app.kEnd);
                app.FLAGS(:,:,:)=NaN;
                app.FLAGS(:,:,app.kMid)=MPOL(:,19+app.NGates:19+app.NGates*2);


                %% ---------------------------------------------------------------
                %% MANAGE VARIABLES CONTAINING MEASUREMENTS INFORMATION - WITH IP
                %% Creates GATES (gates of M, which is TAB{7} excluding the DC gate
                DCgate=str2num(char(TAB{10}(2)));
                app.GATES=TAB{7}(1:end ~=DCgate,:);

                %% Calculate integral chargeability:
                %% sum(M(i) * Gate Width(i)) / sum(all gates width)
                %% (only for uncommented gates)

                GateWidth=app.GATES(:,2)';
                for j=1:height(MPOL)
                    M=MPOL(j,26+app.NGates*2:24+app.NGates*3);
                    num=0;
                    tTOT=0;
                    for i=1:app.NGates-1
                        if app.FLAGS(j,2+i,app.kMid)==0
                            num=num+(M(i)*GateWidth(i));  % numerator
                            tTOT=tTOT+GateWidth(i);
                        end
                    end

                    intM=num/tTOT;
                    MPOL(j,25+app.NGates*2)=intM;
                end
            end

            %% -----------------------------------------------------------
            %% DIVIDE MEASUREMENTS FOR PSEUDODEPTH AND GEOMETRY
            %% Find quadrupole side (geometry of the acquisition)
            PdepthSorted=sort(unique(MPOL(:,14)));
            XfocusSorted=sort(unique(MPOL(:,12)));  %%TOGLIERE?


            %% QdrpSide: array nx1 with n=height(MPOL) --> the row of QdrpSide refers to the row of MPOL
            NMpoles=height(MPOL);
            for l=1:NMpoles
                Qdrp=sort([MPOL(l,5) MPOL(l,7) MPOL(l,10) MPOL(l,11)]); %[A B M N]
                if Qdrp(3)-Qdrp(1)<Qdrp(4)-Qdrp(2)
                    QdrpSide(l,1)=1; % Left
                elseif Qdrp(3)-Qdrp(1)>Qdrp(4)-Qdrp(2)
                    QdrpSide(l,1)=2; % Right
                elseif Qdrp(3)-Qdrp(1)==Qdrp(4)-Qdrp(2)
                    QdrpSide(l,1)=0;  % - (Symmetric)
                end
            end

            %% SetPdepth: cell array nx1 with n number of sets of mesurements with same pseudodepth
            %% In each cell: nx6 with n number of
            % measurements with the same pseudodepth
            % Columns: 1) ID (row in MPOL)
            %          2) Lateral focus
            %          3) Pseudodepth
            %          4) Apparent resistivity
            %          5) Integral chargeability (if there is
            %             also in .qui/.quo file)
            %          6) Symmetry
            %             [0: symmetric, 1: left, 2: right]

            for i=1:height(PdepthSorted)
                SetPdepth{i}=find(MPOL(:,14)==PdepthSorted(i,1));
            end
            SetPdepth=SetPdepth';

            for m=1:height(SetPdepth)
                for n=1:height(SetPdepth{m})
                    SetPdepth{m}(n,2)=MPOL(SetPdepth{m}(n,1),12);   % Lateral focus
                    SetPdepth{m}(n,3)=MPOL(SetPdepth{m}(n,1),14);   % Pseudodepth
                    SetPdepth{m}(n,4)=MPOL(SetPdepth{m}(n,1),22+app.NGates*2);   % Rhoa
                    if width(MPOL)>25
                        SetPdepth{m}(n,5)=MPOL(SetPdepth{m}(n,1),25+app.NGates*2);  % intM
                    end
                    SetPdepth{m}(n,6)=QdrpSide(SetPdepth{m}(n,1),1);  % Side = geometry
                end
            end

            for j=1:height(SetPdepth)
                SetPdepth{j}=sortrows(SetPdepth{j},2);    % Sort for pseudodepth
            end

            %% app.SetpdepthSide: like SetPdepth but the cells are divided also for geometry
            % (see private properties)
            app.SetPdepthSide=cell(height(SetPdepth),3);

            for i=1:height(SetPdepth)
                for j=1:height(SetPdepth{i})
                    if SetPdepth{i}(j,6)==0       % Symmetric
                        app.SetPdepthSide{i,1}=[app.SetPdepthSide{i,1};SetPdepth{i}(j,:)];
                    elseif SetPdepth{i}(j,6)==1   % Left
                        app.SetPdepthSide{i,2}=[app.SetPdepthSide{i,2};SetPdepth{i}(j,:)];
                    elseif SetPdepth{i}(j,6)==2   % Right
                        app.SetPdepthSide{i,3}=[app.SetPdepthSide{i,3};SetPdepth{i}(j,:)];
                    end
                end
            end


            %% app.NameSetPdepthSide: string array with pseudodepth and multipole geometry
            % of each cell in app.SetpdepthSide (displayed name of the nodes in app.Tree)
            app.NameSetPdepthSide=strings(height(SetPdepth),3);

            for m=1:height(app.SetPdepthSide)
                for n=1:3
                    if ~isempty(app.SetPdepthSide{m,n})
                        app.nodeDataProfile{m,n}=uitreenode(app.Tree);

                        if app.SetPdepthSide{m,n}(:,6)==0        % Symmetric
                            app.NameSetPdepthSide(m,n)=num2str(app.SetPdepthSide{m,n}(1,3));

                        elseif app.SetPdepthSide{m,n}(:,6)==1    % Left
                            app.NameSetPdepthSide(m,n)=append(num2str(app.SetPdepthSide{m,n}(1,3)),' Left');

                        elseif app.SetPdepthSide{m,n}(:,6)==2    % Right
                            app.NameSetPdepthSide(m,n)=append(num2str(app.SetPdepthSide{m,n}(1,3)),' Right');

                        end
                        app.nodeDataProfile{m,n}.Text=app.NameSetPdepthSide(m,n);  % Add nodes to Data Profile tree
                        app.Tree.CheckedNodes=[app.Tree.CheckedNodes; app.nodeDataProfile{m,n}];   % Add all new nodes to the cheched ones array
                    end
                end
            end

            %% List of rows in MPOL that correspond to a checked node
            app.ROWSChecked=1:1:height(MPOL);
            app.ROWSChecked=app.ROWSChecked';

            %% -------------------------------------------------------------------
            %% CREATE PROPERTIES
            app.TAB=TAB;
            app.MPOL=MPOL;
            app.RAWDATA=RAWDATA;
            app.DIST=DIST;
            app.QuiFile=QuiFile;

            %% ----------------------------------------------------------------------
            %% DEFINE MIN E MAX VALUES OF AXIS

            %% Distance from the start of the profile
            app.MinDist = min(app.DIST(:,2));
            app.MaxDist = max(app.DIST(:,2));

            %% Rhoa
            app.MinRhoa = min(app.MPOL(:,22+app.NGates*2));
            app.MaxRhoa = max(app.MPOL(:,22+app.NGates*2));

            if width(app.MPOL)>25
                %% M (absolute value, min > 0)
                A = abs(app.MPOL(:,26+app.NGates*2:24+app.NGates*3));
                app.MinAbsM = min(A(A>0), [],'all');
                app.MaxAbsM = max(abs(app.MPOL(:,26+app.NGates*2:24+app.NGates*3)), [],'all') ;

                %% M (10% and 90% of inverse hyperbolic sine)
                B = asinh(10000*app.MPOL(:,26+app.NGates*2:24+app.NGates*3));
                B=B(:);
                B2 = sort(abs(B));
                app.MinAsinhM=B2(floor(0.1*(size(B2,1))));
                app.MaxAsinhM=B2(floor(0.9*(size(B2,1))));

                %% intM (10% and 90% of absolute value)
                C = app.MPOL(:,25+app.NGates*2);
                C=C(:);
                C2 = sort(abs(C));
                app.MinIntM10 = C2(floor(0.1*(size(C2,1))));
                app.MaxIntM90 = C2(floor(0.9*(size(C2,1))));
            end

            %% Rhoa (10% and 90% of absolute value)
            D = app.MPOL(:,22+app.NGates*2);
            D=D(:);
            D2 = sort(abs(D));
            app.MinRhoa10 = D2(floor(0.1*(size(D2,1))));
            app.MaxRhoa90 = D2(floor(0.9*(size(D2,1))));

            %% ---------------------------------------------------------------------------
            %% CREATE COLORSCALES
            %% Misfit color scale (for Pseudosection and Patchwork)
            app.MisfitCM = zeros(256,3);
            app.MisfitCM(64:128, 1) = linspace(0, 1, 128-64+1);
            app.MisfitCM(128:256, 1) = linspace(1, 1, 256-128+1);

            app.MisfitCM(1:64, 2) = linspace(0, 1, 64-1+1);
            app.MisfitCM(64:192, 2) = linspace(1, 1, 192-64+1);
            app.MisfitCM(192:256, 2) = linspace(1, 0, 256-192+1);

            app.MisfitCM(1:128, 3) = linspace(1, 1, 128-1+1);
            app.MisfitCM(128:192, 3) = linspace(1, 0, 192-128+1);

            %% Chargeability colorscale (for Patchwork, with negative values)
            app.MCM = zeros(256,3);
            app.MCM(65:256,1:3) = jet(192);
            app.MCM(1:64, 1) = linspace(0.4, 1, 64-1+1);
            app.MCM(1:64, 3) = linspace(0.8, 1, 64-1+1);

            %% ------------------------------------------------------------------
            %% SETTINGS
            %% Positions
            hold(app.UIAxes,'on')
            app.settingsPositions=cell(2,1);

            app.settingsPositions{1}(1,:)=[app.MinDist app.MaxDist];
            app.settingsPositions{1}(2,:)=[min(app.TAB{4}(:,5))-0.5 max(app.TAB{4}(:,5))+0.5];

            app.UIAxes.XLim=app.settingsPositions{1}(1,:);
            app.UIAxes.YLim=app.settingsPositions{1}(2,:);

            app.settingsPositions{2}=app.settingsPositions{1};


            %% Profiles
            app.UIAxes2.YScale='log';
            hold(app.UIAxes2,'on')
            app.settingsProfile=cell(2,1);

            app.settingsProfile{1}(1,:)=[app.MinDist app.MaxDist];
            app.settingsProfile{1}(2,:)=[app.MinRhoa/1.5 app.MaxRhoa*1.5];

            app.UIAxes2.XLim=app.settingsProfile{1}(1,:);
            app.UIAxes2.YLim=app.settingsProfile{1}(2,:);

            app.settingsProfile{2}=app.settingsProfile{1};


            %% Pseudosection
            hold(app.UIAxes3,'on')
            app.settingsPseudosec=cell(2,1);

            app.settingsPseudosec{1}(1,:)=[app.MinDist app.MaxDist];
            app.settingsPseudosec{1}(2,:)=[min(app.MPOL(:,14))-5 max(app.MPOL(:,14))+5];


            app.UIAxes3.XLim=app.settingsPseudosec{1}(1,:);
            app.UIAxes3.YLim=app.settingsPseudosec{1}(2,:);

            app.settingsPseudosec{2}=app.settingsPseudosec{1};

            app.fixAxisPseudosection;


            %% Stripe
            if width(app.MPOL)>25
                app.UIAxes3_2.YScale='log';
                hold(app.UIAxes3_2,'on')
                app.settingsStripe=cell(3,1);

                for i=1:3
                    if ~isempty(app.SetPdepthSide{1,i})
                        Diff= app.SetPdepthSide{1,i}(2,2)-app.SetPdepthSide{1,i}(1,2);
                        break
                    end
                end
                app.settingsStripe{3}(1,1)=Diff*50;  %%%%%% Da trovare soluzione


                app.settingsStripe{2}(1,:)=[app.MinDist-1 app.MinDist+app.settingsStripe{3}];
                app.settingsStripe{2}(2,:)=[app.MinAbsM/2 app.MaxAbsM*2];

                app.UIAxes3_2.XLim=app.settingsStripe{2}(1,:);
                app.UIAxes3_2.YLim=app.settingsStripe{2}(2,:);


                %% Decays
                app.UIAxes4.XScale='log';
                app.UIAxes4.YScale='log';
                hold(app.UIAxes4,'on')
                app.settingsDecay=cell(4,1);

                app.settingsDecay{1}(1,:)=[app.GATES(1,3)/2 app.GATES(end,3)*2];
                app.settingsDecay{1}(2,:)=[app.MinAbsM/2 app.MaxAbsM*2];

                app.UIAxes4.XLim=app.settingsDecay{1}(1,:);
                app.UIAxes4.YLim=app.settingsDecay{1}(2,:);

                app.settingsDecay{2}=app.settingsDecay{1};


                %% Patchwork
                for i=1:3
                    if ~isempty(app.SetPdepthSide{1,i})
                        D = height(app.SetPdepthSide{1,i});
                        break
                    end
                end

                hold(app.UIAxes3_3,'on')
                app.settingsPatchwork=cell(2,1);

                app.settingsPatchwork{1}(1,:)=[0 D+1];
                app.settingsPatchwork{1}(2,:)=[0 app.NGates+5];

                app.UIAxes3_3.XLim=app.settingsPatchwork{1}(1,:);
                app.UIAxes3_3.YLim=app.settingsPatchwork{1}(2,:);
                app.settingsPatchwork{2}=app.settingsPatchwork{1};

                app.fixAxisPatchwork;
            end


            %% Plot functions
            app.EnableKeyboardShortCuts;
            app.PlotPosition;
            app.PlotDataProfile;
            app.PlotPseudosection;

        end

        % Button pushed function: SettingsButton_positions
        function SettingsButton_positionsPushed(app, event)
            app.SettingsButton_positions.Enable='off';
            app.settings_positionsapp=settings_positionsapp(app,app.settingsPositions);
        end

        % Button pushed function: SettingsButton_profile
        function SettingsButton_profilePushed(app, event)
            app.SettingsButton_profile.Enable='off';
            app.settings_profileapp=settings_profileapp(app,app.settingsProfile);
        end

        % Button pushed function: SettingsButton_pseudosec
        function SettingsButton_pseudosecPushed(app, event)
            app.SettingsButton_pseudosec.Enable='off';
            app.settings_pseudosecapp=settings_pseudosecapp(app,app.settingsPseudosec);
        end

        % Button pushed function: SettingsButton_decay
        function SettingsButton_decayPushed(app, event)
            app.SettingsButton_decay.Enable='off';
            app.settings_decayapp=settings_decayapp(app,app.settingsDecay);
        end

        % Button pushed function: SettingsButton_stripe
        function SettingsButton_stripePushed(app, event)
            app.SettingsButton_stripe.Enable='off';
            app.settings_stripeapp=settings_stripeapp(app,app.settingsStripe);
        end

        % Button pushed function: SettingsButton_patchwork
        function SettingsButton_patchworkPushed(app, event)
            app.SettingsButton_patchwork.Enable='off';
            app.settings_patchworkapp=settings_patchworkapp(app,app.settingsPatchwork);
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % SHOW OR HIDE THE SETS IN DATA PROFILES BASED ON CHECKED NODES
            % IN THE SETS TREE

            hold(app.UIAxes2,'on')
            cla(app.UIAxes2)
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            plotDataProfile=app.plotDataProfile;
            plotSelectedProfile=app.plotSelectedProfile;
            %             psecSelectedProfile=app.psecSelectedProfile;
            SetPdepthSide=app.SetPdepthSide;
            CShiftvalue = app.CurveshiftfactorSpinner.Value;
            ROWSChecked=app.ROWSChecked;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            app.PlotDataProfile;
            if ismember(selectedNodes,checkedNodes)==0
                delete(plotSelectedProfile);

                for f=1:height(SetPdepthSide{m,n})
                    d(f)=find(ROWSChecked==SetPdepthSide{m,n}(f,1));
                    ROWSChecked(d(f),:)=[];
                end


            else
                index=height(SetPdepthSide)-(m-1);
                %                 RhoaShift=zeros(height(SetPdepthSide{m,n}),1);

                for p=1:height(SetPdepthSide{m,n})
                    %                     RhoaShift(p,1)=SetPdepthSide{m,n}(p,4).*CShiftvalue^index;
                    ROWSChecked=[ROWSChecked; SetPdepthSide{m,n}(p,1)];
                end
                %                     plotDataProfile{m,n}=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),RhoaShift(:,1),'k.-');
                plotSelectedProfile=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),SetPdepthSide{m,n}(:,4)*CShiftvalue^index,'.-','LineWidth',5,'Color',[0 0.5 1 0.3]);

            end

            app.plotSelectedProfile=plotSelectedProfile;
            app.ROWSChecked=ROWSChecked;


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            checkedNodes = app.Tree.CheckedNodes;
            SetPdepthSide=app.SetPdepthSide;
            plotSelectedProfile=app.plotSelectedProfile;
            NGates=app.NGates;
            DIST=app.DIST;

            [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);

            if ~isempty(plotSelectedProfile)
                delete (plotSelectedProfile);
            end

            if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') | isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)') | isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)')
                ind=1;
            elseif isequal(app.TypeDropDown_profile.Value,'intM (data)') | isequal(app.TypeDropDown_profile.Value,'intM (forward)') | isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                ind=2;
            end

            %% Hilight SelectedProfile
            if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'intM (data)')
                SetPdepthSide=app.SetPdepthSide;
                isMisfit=0;
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_profile.Value,'intM (forward)')
                SetPdepthSide=app.fSetPdepthSide;
                isMisfit=0;
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                SetPdepthSide=app.mSetPdepthSide;
                isMisfit=1;
            end

            if ismember(selectedNodes,checkedNodes)==1

                index=height(SetPdepthSide)-(m-1);
                CShiftvalue = app.CurveshiftfactorSpinner.Value;
                if isMisfit==1
                    plotSelectedProfile=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),SetPdepthSide{m,n}(:,ind+3),'o','LineWidth',1,'Color',[0 0.5 1 0.3]);
                else
                    plotSelectedProfile=plot(app.UIAxes2,SetPdepthSide{m,n}(:,2),SetPdepthSide{m,n}(:,ind+3)*CShiftvalue^index,'.-','LineWidth',5,'Color',[0 0.5 1 0.3]);
                end

                app.plotSelectedProfile=plotSelectedProfile;
            end


            %% Change Stripe
            if width(app.MPOL)>25

                startInt=min(SetPdepthSide{m,n}(:,2));
                endInt=startInt+app.settingsStripe{3};

                app.settingsStripe{2}(1,:)=[startInt endInt];
                app.UIAxes3_2.XLim=app.settingsStripe{2}(1,:);

                app.MSet=cell(app.NGates,1);
                app.LineMSet=cell(app.NGates,1);
                app.Xfocus=cell(app.NGates,1);
                app.Std_neg=cell(app.NGates,1);
                app.Std_pos=cell(app.NGates,1);

                %% Plot data stripe
                app.PlotDataStripe;
                app.PlotPatchwork;
            end

        end

        % Button pushed function: Previous
        function PreviousPushed(app, event)
            % SHOW THE DECAYS IN THE SEGMENT BEFORE THE CURRENT ONE IN DATA
            % STRIPE

            app.Next.Enable = 'on';
            app.Previous.Enable = 'on';


            %% Change X axis
            startInt=app.settingsStripe{2}(1,1)-app.settingsStripe{3};
            if startInt>min(app.DIST(:,2))
                endInt=app.settingsStripe{2}(1,2);
            else
                startInt=min(app.DIST(:,2));
                endInt=min(app.DIST(:,2))+app.settingsStripe{3};
            end

            app.settingsStripe{2}(1,:)=[startInt endInt];
            app.UIAxes3_2.XLim=app.settingsStripe{2}(1,:);

            if app.UIAxes3_2.XLim(1,1)<=min(app.DIST(:,2))
                app.Previous.Enable = 'off';
            end
            if app.UIAxes3_2.XLim(1,2)>=max(app.DIST(:,2))
                app.Next.Enable = 'off';
            end

        end

        % Button pushed function: Next
        function NextPushed(app, event)
            % SHOW THE DECAYS IN THE SEGMENT AFTER THE CURRENT ONE IN DATA
            % STRIPE

            app.Next.Enable = 'on';
            app.Previous.Enable = 'on';


            %% Change X axis
            startInt=app.settingsStripe{2}(1,1)+app.settingsStripe{3};
            if (startInt+app.settingsStripe{3})<max(app.DIST(:,2))
                endInt=startInt+app.settingsStripe{3};
            else
                startInt=max(app.DIST(:,2))-app.settingsStripe{3};
                endInt=max(app.DIST(:,2));
            end

            app.settingsStripe{2}(1,:)=[startInt endInt];
            app.UIAxes3_2.XLim=app.settingsStripe{2}(1,:);

            if app.UIAxes3_2.XLim(1,1)<=min(app.DIST(:,2))
                app.Previous.Enable = 'off';
            end
            if app.UIAxes3_2.XLim(1,2)>=max(app.DIST(:,2))
                app.Next.Enable = 'off';
            end

        end

        % Button pushed function: ShowallButton
        function ShowallButtonPushed(app, event)
            % SHOW ALL SETS IN DATA PROFILES
            hold(app.UIAxes2,'on')

            for m=1:height(app.SetPdepthSide)
                for n=1:3
                    if ~isempty(app.SetPdepthSide{m,n})
                        app.Tree.CheckedNodes=[app.Tree.CheckedNodes; app.nodeDataProfile{m,n}];
                    end
                end
            end

            app.ROWSChecked=[];
            app.ROWSChecked=1:1:height(app.MPOL);
            app.ROWSChecked=app.ROWSChecked';

            app.PlotDataProfile;
            app.Shown;
        end

        % Button pushed function: HideallButton
        function HideallButtonPushed(app, event)
            % HIDE ALL SETS IN DATA PROFILES
            app.ROWSChecked=[];
            app.Tree.CheckedNodes=[];
            cla(app.UIAxes2);
        end

        % Value changed function: HiderejectedpointsCheckBox
        function HiderejectedpointsCheckBoxValueChanged(app, event)
            % DON'T SHOW THE GREY POINTS AND LINES
            if width(app.MPOL)>25
                app.PlotDecay;
                if ~isempty(app.Tree.SelectedNodes)
                    app.PlotDataStripe;
                end
            end
            app.PlotDataProfile;
            app.PlotPseudosection;
            app.Shown;
        end

        % Value changed function: HideerrorbarsCheckBox
        function HideerrorbarsCheckBoxValueChanged(app, event)
            if ~isempty(app.Tree.SelectedNodes)
                app.PlotDataStripe;
            end
        end

        % Value changed function: CurveshiftfactorSpinner
        function CurveshiftfactorSpinnerValueChanged(app, event)
            % RE-PLOT THE DATA PROFILES WHEN THE SHIFT FACTOR IS CHANGED
            app.PlotDataProfile;
            app.fixAxisProfiles;
            app.Shown;
        end

        % Value changed function: TypeDropDown_profile
        function TypeDropDown_profileValueChanged(app, event)
            if isequal(app.TypeDropDown_profile.Value,'Rhoa (data)') || isequal(app.TypeDropDown_profile.Value,'intM (data)')  ||  isequal(app.TypeDropDown_profile.Value,'Rhoa (forward)')  || isequal(app.TypeDropDown_profile.Value,'intM (forward)');
                app.CurveshiftfactorSpinner.Enable='on';
            elseif isequal(app.TypeDropDown_profile.Value,'Rhoa (misfit)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')
                app.CurveshiftfactorSpinner.Value=1;
                app.CurveshiftfactorSpinner.Enable='off';
            end

            app.PlotDataProfile;
            app.fixAxisProfiles;
            app.Shown;
        end

        % Value changed function: TypeDropDown_pseudosection
        function TypeDropDown_pseudosectionValueChanged(app, event)
            app.PlotPseudosection;
            app.fixAxisPseudosection;
            app.Shown;
        end

        % Value changed function: TypeDropDown_decays
        function TypeDropDown_decaysValueChanged(app, event)
            app.PlotDecay;
        end

        % Value changed function: TypeDropDown_stripe
        function TypeDropDown_stripeValueChanged(app, event)
            app.LineMSet=cell(app.NGates,1);
            app.PlotDataStripe;
        end

        % Value changed function: TypeDropDown_patchwork
        function TypeDropDown_patchworkValueChanged(app, event)
            app.MSet=cell(app.NGates,1);
            app.fixAxisPatchwork;
            app.PlotPatchwork;
        end

        % Button pushed function: ColorbarEditorButton
        function ColorbarEditorButtonPushed(app, event)
            colormapeditor(app.UIAxes3);
        end

        % Button pushed function: ColorbarEditorButton_2
        function ColorbarEditorButton_2Pushed(app, event)
            colormapeditor(app.UIAxes3_3);
        end

        % Button pushed function: RawdataButton
        function RawdataButtonPushed(app, event)

        end

        % Button pushed function: AddforwardfileButton
        function AddforwardfileButtonPushed(app, event)
            app.UploadForward;
        end

        % Value changed function: ForwardEditField
        function ForwardEditFieldValueChanged(app, event)

        end

        % Button pushed function: UpdateintMvaluesButton
        function UpdateintMvaluesButtonPushed(app, event)
            MPOL=app.MPOL;
            fMPOL=app.fMPOL;
            SetPdepthSide=app.SetPdepthSide;
            fSetPdepthSide=app.fSetPdepthSide;

            GateWidth=app.GATES(:,2)';

            for j=1:height(MPOL)
                M=MPOL(j,26+app.NGates*2:24+app.NGates*3);
                num=0;
                tTOT=0;
                for i=1:app.NGates-1
                    if app.FLAGS(j,2+i,app.kMid)==0
                        num=num+(M(i)*GateWidth(i));
                        tTOT=tTOT+GateWidth(i);
                    end
                end

                intM=num/tTOT;
                MPOL(j,25+app.NGates*2)=intM;
            end

            for i=1:3
                for j=1:height(SetPdepthSide)
                    if ~isempty(SetPdepthSide{j,i})
                        SetPdepthSide{j,i}(:,5)=MPOL( SetPdepthSide{j,i}(:,1),25+app.NGates*2);
                    end
                end
            end


            for j=1:height(fMPOL)
                M=fMPOL(j,26+app.NGates*2:24+app.NGates*3);
                num=0;
                tTOT=0;
                for i=1:app.NGates-1
                    if app.FLAGS(j,2+i,app.kMid)==0
                        num=num+(M(i)*GateWidth(i));
                        tTOT=tTOT+GateWidth(i);
                    end
                end

                intM=num/tTOT;
                fMPOL(j,25+app.NGates*2)=intM;
                app.mMPOL(j,25+app.NGates*2)=MPOL(j,25+app.NGates*2)./fMPOL(j,25+app.NGates*2);
            end


            for i=1:3
                for j=1:height(fSetPdepthSide)
                    if ~isempty(fSetPdepthSide{j,i})

                        fSetPdepthSide{j,i}(:,5)=fMPOL( fSetPdepthSide{j,i}(:,1),25+app.NGates*2);
                        app.mSetPdepthSide{j,i}(:,5)=SetPdepthSide{j,i}(:,5)./fSetPdepthSide{j,i}(:,5);
                    end
                end
            end

            app.fMPOL=fMPOL;
            app.fSetPdepthSide=fSetPdepthSide;


            if isequal(app.TypeDropDown_profile.Value,'intM (data)') || isequal(app.TypeDropDown_profile.Value,'intM (forward)') || isequal(app.TypeDropDown_profile.Value,'intM (misfit)')

                app.PlotDataProfile;
                app.fixAxisProfiles;
            end

            if isequal(app.TypeDropDown_pseudosection.Value,'intM (data)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (forward)') || isequal(app.TypeDropDown_pseudosection.Value,'intM (misfit)')

                app.PlotPseudosection;
                app.fixAxisPseudosection;
            end

            app.Shown;


            updated=uiconfirm(app.UIFigure,'Updated', 'Updating intM','Icon','success','Options',{'OK'});


        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            if width(app.MPOL)>25

                app.MSet=cell(app.NGates,1);
                app.LineMSet=cell(app.NGates,1);
                app.Xfocus=cell(app.NGates,1);
                app.Std_neg=cell(app.NGates,1);
                app.Std_pos=cell(app.NGates,1);
            end


            if app.TabGroup2.SelectedTab == app.DataprofileandPseudosectionTab
                cla(app.UIAxes3_2)
                cla(app.UIAxes3_3)
                app.Frame=1;
                if width(app.MPOL)>25
                    app.PlotDecay;
                end
                app.PlotDataProfile;
                app.PlotPseudosection;
                app.Shown;

            elseif app.TabGroup2.SelectedTab == app.DatastripeTab
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes3_3)
                app.Frame=2;
                if width(app.MPOL)>25
                    app.PlotDecay;
                    if ~isempty(app.Tree.SelectedNodes)
                        app.PlotDataStripe;
                    end
                end
                app.Shown;

            elseif app.TabGroup2.SelectedTab == app.PatchworkTab
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes3_2)
                app.Frame=3;
                if width(app.MPOL)>25
                    app.PlotDecay;
                    if ~isempty(app.Tree.SelectedNodes)
                        app.PlotPatchwork;
                    end
                end
                app.Shown;
            end

        end

        % Menu selected function: ListofshortcutsMenu
        function ListofshortcutsMenuSelected(app, event)
            TextFigure = uifigure ('Position',[450 150 750 550]);
            TextFigure.Icon = 'LOGO-provvisorio.png';

            TextBox = uitextarea(TextFigure,'Position',[1 1 750 550],'Editable',"off");
            TextBox.Value = ["LIST OF SHORCUTS";"";"To remove/restore data click on a plot and draw a square, then use one of the key combinations listed. " ; ""; ...
                "In DATA PROFILE and PSEUDOSECTION:" ; ...
                "• Alt+Q    remove the selected measurements;" ; ...
                "• Alt+A    restore the selected measurements;" ;
                "• ALT+R    remove all the measurements with a value ≥ to the lower of the selected ones;" ;
                "• ALT+F    restore all the measurements with a value ≥ to the lower of the selected ones;" ;
                "• ALT+T    remove all the measurements with a value ≤ to the higher of the selected ones;" ;
                "• ALT+G    restore all the measurements with a value ≤ to the higher of the selected ones;" ;
                "          " ; ...
                "In DECAYS and DATA STRIPE:" ; ...
                "• Alt+Q    remove the selected gates;" ; ...
                "• Alt+A    restore the selected gates;" ; ...
                "• Alt+W    remove from the first gate in the plot to the last gate selected, for the decays in which there is a selection;" ; ...
                "• Alt+S    restore from the first gate in the plot to the last gate selected, for the decays in which there is a selection;" ; ...
                "• Alt+E    remove from the first selected gate to the last gate in the plot, for the decays in which there is a selection;" ; ...
                "• Alt+D    restore from the first selected gate to the last gate in the plot, for the decays in which there is a selection;" ; ...
                "• Alt+N    remove the selected gates, if negative;" ; ...
                "• Alt+M    restore the selected gates, if positive;" ; ...
                "• ALT+R    remove all the gates with a value ≥ to lower of the selected ones, for the decays in which there is a selection;"; ...
                "• ALT+F    restore all the gates with a value ≥ to lower of the selected ones, for the decays in which there is a selection;"; ...
                "• ALT+T    remove all the gates with a value ≤ to the higher of the selected ones, for the decays in which there is a selection;"; ...
                "• ALT+G    restore all the gates with a value ≤ to the higher of the selected ones, for the decays in which there is a selection"; ...
                "• Shift+Q  remove all the gates from the decays in which there is a selection;" ; ...
                "• Shift+A  restore all the gates from the decays in which there is a selection;" ; ...
                "• Shift+N  remove all the negative gates from the decays in which there is a selection;" ; ...
                "• Shift+M  restore all the positive gates from the decays in which there is a selection. ";
                "             " ; ...
                "Additionally, only in DATA STRIPE and PATCHWORK:" ; ...
                "• Ctrl+Q   remove all the gates selected from all the decays of the set;" ; ...
                "• Ctrl+A   restore all the gates selected from all the decays of the set;" ; ...
                "• Ctrl+N   remove all the gates selected from all the decays of the set, if negative;" ; ...
                "• Ctrl+M   restore all the gates selected from all the decays of the set, if positive. ";
                "• Ctrl+W   remove from the first gate in the plot to the last gate selected, for all the decays of the set;" ; ...
                "• Ctrl+S   restore from the first gate in the plot to the last gate selected, for all the decays of the set;" ; ...
                "• Ctrl+E   remove from the first selected gate to the last gate in the plot, for all the decays of the set;" ; ...
                "• Ctrl+D   restore from the first selected gate to the last gate in the plot, for all the decays of the set;" ;
                "           " ; ...
                "Without the selection needed and for all the plots:" ; ...
                "• Ctrl+Z   'undo’, to reverse the last action (maximum 10 times);" ; ...
                "• Ctrl+Y   'redo’, to reverse the last ‘undo’ action (maximum 10 times)." ; ...
                "" ];
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            if isequal(char(event.Modifier),'alt')

                switch key
                    case 'q'            % ALT+Q: remove the selected measurements
                        app.FindSelectedRhoa;

                        if  ~isempty(app.rowFLAGSxRhoa)

                            if app.Parameter=='Rhoa'
                                index=1;
                            elseif app.Parameter=='intM'
                                index=2;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            for i=1:height(app.rowFLAGSxRhoa)
                                app.FLAGS(app.rowFLAGSxRhoa(i),index,app.kMid)=1;
                            end


                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;

                        else
                            app.FindSelectedGates;
                            if ~isempty(app.rowFLAGS)

                                for k=2:app.kMid
                                    app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                                end
                                for i=1:height(app.rowFLAGS)
                                    for j=1:length(app.colFLAGS{app.rowFLAGS(i),1})
                                        app.FLAGS(app.rowFLAGS(i),app.colFLAGS{app.rowFLAGS(i),1}(j),app.kMid)=1;
                                    end
                                end

                                app.PlotDecay;
                                if ~isempty(app.Tree.SelectedNodes)
                                    app.PlotDataStripe;
                                    app.PlotPatchwork;
                                end

                            end
                        end

                    case 'a'        %  ALT+A: restore the selected measurements

                        app.FindSelectedRhoa;

                        if  ~isempty(app.rowFLAGSxRhoa)

                            if app.Parameter=='Rhoa'
                                index=1;
                            elseif app.Parameter=='intM'
                                index=2;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            for i=1:height(app.rowFLAGSxRhoa)
                                app.FLAGS(app.rowFLAGSxRhoa(i),index,app.kMid)=0;
                            end

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;

                        else
                            app.FindSelectedGates;
                            if ~isempty(app.rowFLAGS)

                                for k=2:app.kMid
                                    app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                                end
                                for i=1:height(app.rowFLAGS)
                                    for j=1:length(app.colFLAGS{app.rowFLAGS(i),1})
                                        app.FLAGS(app.rowFLAGS(i),app.colFLAGS{app.rowFLAGS(i),1}(j),app.kMid)=0;
                                    end
                                end

                                app.PlotDecay;
                                if ~isempty(app.Tree.SelectedNodes)
                                    app.PlotDataStripe;
                                    app.PlotPatchwork;
                                end

                            end
                        end

                    case 'w'            % ALT+W: remove from the first gate in
                        % the plot to the last gate selected, for the decays
                        % in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            Last=length(app.colFLAGS{app.rowFLAGS(i),1});
                            app.FLAGS(app.rowFLAGS(i),3:app.colFLAGS{app.rowFLAGS(i),1}(Last),app.kMid)=1;
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 's'            % ALT+S: restore from the first gate in
                        % the plot to the last gate selected, for the decays
                        % in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            Last=length(app.colFLAGS{app.rowFLAGS(i),1});
                            app.FLAGS(app.rowFLAGS(i),3:app.colFLAGS{app.rowFLAGS(i),1}(Last),app.kMid)=0;
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'e'           % ALT+E: remove from the first selected gate
                        % to the last gate in the plot, for the decays
                        % in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            app.FLAGS(app.rowFLAGS(i),app.colFLAGS{app.rowFLAGS(i),1}(1):end,app.kMid)=1;
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'd'            % ALT+D: restore from the first selected gate
                        % to the last gate in the plot, for the decays
                        % in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            app.FLAGS(app.rowFLAGS(i),app.colFLAGS{app.rowFLAGS(i),1}(1):end,app.kMid)=0;
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'n'            % ALT+N: remove the selected gates, if negative

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            for j=1:length(app.colFLAGS{app.rowFLAGS(i),1})
                                if app.MPOL(app.rowFLAGS(i),23+app.NGates*2+app.colFLAGS{app.rowFLAGS(i),1}(j))<0
                                    app.FLAGS(app.rowFLAGS(i),app.colFLAGS{app.rowFLAGS(i),1}(j),app.kMid)=1;
                                end
                            end
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'm'            % ALT+M: restore the selected gates, if positive

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            for j=1:length(app.colFLAGS{app.rowFLAGS(i),1})
                                if app.MPOL(app.rowFLAGS(i),23+app.NGates*2+app.colFLAGS{app.rowFLAGS(i),1}(j))>0
                                    app.FLAGS(app.rowFLAGS(i),app.colFLAGS{app.rowFLAGS(i),1}(j),app.kMid)=0;
                                end
                            end
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'r'            % [Rhoa processing] ALT+R: remove all the measurements with a value ≥ to the lower of the selected ones
                        % [M processing] ALT+R: remove all the gates with a value ≥ to higher of the selected ones,
                        % for the decays in which there is a selection

                        app.FindSelectedRhoa;
                        if  ~isempty(app.rowFLAGSxRhoa)

                            if app.Parameter=='Rhoa'
                                index=1;
                                col=22+2*app.NGates;


                            elseif app.Parameter=='intM'
                                index=2;
                                col=25+2*app.NGates;
                            end

                            if app.Type=='data'
                                MPOL=app.MPOL;
                            elseif app.Type=='forw'
                                MPOL=app.fMPOL;
                            elseif app.Type=='misf'
                                MPOL=app.mMPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            Min=min(MPOL(app.rowFLAGSxRhoa,col));
                            Maj=find(MPOL(:,col) >= Min);
                            app.FLAGS(Maj,index,app.kMid)=1;

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;
                        else

                            app.FindSelectedGates;

                            if app.Graph=='patc'
                                if app.Type=='data'
                                    MPOL=app.MPOL;
                                elseif app.Type=='forw'
                                    MPOL=app.fMPOL;
                                elseif app.Type=='misf'
                                    MPOL=app.mMPOL;
                                end
                            else
                                MPOL=app.MPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            for i=1:height(app.rowFLAGS)
                                Min=min(MPOL(app.rowFLAGS(i),23+2*app.NGates+app.colFLAGS{app.rowFLAGS(i),1}));
                                Maj=find(MPOL(app.rowFLAGS(i),26+2*app.NGates:24+3*app.NGates) >= Min);
                                app.FLAGS(app.rowFLAGS(i),Maj+2,app.kMid)=1;
                            end

                            app.PlotDecay;
                            if ~isempty(app.Tree.SelectedNodes)
                                app.PlotDataStripe;
                                app.PlotPatchwork;
                            end
                        end

                    case 'f'            % [Rhoa processing] ALT+F: restore all the measurements with a value ≥ to the lower of the selected ones
                        % [M processing] ALT+F: restore all the gates with a value ≥ to higher of the selected ones,
                        % for the decays in which there is a selection

                        app.FindSelectedRhoa;
                        if  ~isempty(app.rowFLAGSxRhoa)

                            if app.Parameter=='Rhoa'
                                index=1;
                                col=22+2*app.NGates;
                            elseif app.Parameter=='intM'
                                index=2;
                                col=25+2*app.NGates;
                            end

                            if app.Type=='data'
                                MPOL=app.MPOL;
                            elseif app.Type=='forw'
                                MPOL=app.fMPOL;
                            elseif app.Type=='misf'
                                MPOL=app.mMPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            Min=min(MPOL(app.rowFLAGSxRhoa,col));
                            Maj=find(MPOL(:,col) >= Min);
                            app.FLAGS(Maj,index,app.kMid)=0;

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;
                        else

                            app.FindSelectedGates;

                            if app.Graph=='patc'
                                if app.Type=='data'
                                    MPOL=app.MPOL;
                                elseif app.Type=='forw'
                                    MPOL=app.fMPOL;
                                elseif app.Type=='misf'
                                    MPOL=app.mMPOL;
                                end
                            else
                                MPOL=app.MPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            for i=1:height(app.rowFLAGS)
                                Min=min(MPOL(app.rowFLAGS(i),23+2*app.NGates+app.colFLAGS{app.rowFLAGS(i),1}));
                                Maj=find(MPOL(app.rowFLAGS(i),26+2*app.NGates:24+3*app.NGates) >= Min);
                                app.FLAGS(app.rowFLAGS(i),Maj+2,app.kMid)=0;
                            end

                            app.PlotDecay;
                            if ~isempty(app.Tree.SelectedNodes)
                                app.PlotDataStripe;
                                app.PlotPatchwork;
                            end
                        end

                    case 't'           % [Rhoa processing] ALT+T: remove all the measurements with a value ≤ to the higher of the selected ones
                        % [M processing] ALT+T: remove all the gates with a value ≤ to the lower of the selected ones,
                        % for the decays in which there is a selection

                        app.FindSelectedRhoa;
                        if  ~isempty(app.rowFLAGSxRhoa)

                            if app.Parameter=='Rhoa'
                                index=1;
                                col=22+2*app.NGates;
                            elseif app.Parameter=='intM'
                                index=2;
                                col=25+2*app.NGates;
                            end

                            if app.Type=='data'
                                MPOL=app.MPOL;
                            elseif app.Type=='forw'
                                MPOL=app.fMPOL;
                            elseif app.Type=='misf'
                                MPOL=app.mMPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            Max=max(MPOL(app.rowFLAGSxRhoa,col));
                            Mir=find(MPOL(:,col) <= Max);
                            app.FLAGS(Mir,index,app.kMid)=1;

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;
                        else

                            app.FindSelectedGates;

                            if app.Graph=='patc'
                                if app.Type=='data'
                                    MPOL=app.MPOL;
                                elseif app.Type=='forw'
                                    MPOL=app.fMPOL;
                                elseif app.Type=='misf'
                                    MPOL=app.mMPOL;
                                end
                            else
                                MPOL=app.MPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            for i=1:height(app.rowFLAGS)
                                Max=max(MPOL(app.rowFLAGS(i),23+2*app.NGates+app.colFLAGS{app.rowFLAGS(i),1}));
                                Mir=find(MPOL(app.rowFLAGS(i),26+2*app.NGates:24+3*app.NGates) <= Max);
                                app.FLAGS(app.rowFLAGS(i),Mir+2,app.kMid)=1;
                            end

                            app.PlotDecay;
                            if ~isempty(app.Tree.SelectedNodes)
                                app.PlotDataStripe;
                                app.PlotPatchwork;
                            end
                        end

                    case 'g'            % [Rhoa processing] ALT+G: restore all the measurements with a value ≤ to the higher of the selected ones
                        % [M processing] ALT+G: restore all the gates with a value ≤ to the lower of the selected ones,
                        % for the decays in which there is a selection

                        app.FindSelectedRhoa;
                        if  ~isempty(app.rowFLAGSxRhoa)

                            if app.Parameter=='Rhoa'
                                index=1;
                                col=22+2*app.NGates;
                            elseif app.Parameter=='intM'
                                index=2;
                                col=25+2*app.NGates;
                            end

                            if app.Type=='data'
                                MPOL=app.MPOL;
                            elseif app.Type=='forw'
                                MPOL=app.fMPOL;
                            elseif app.Type=='misf'
                                MPOL=app.mMPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            Max=max(MPOL(app.rowFLAGSxRhoa,col));
                            Mir=find(MPOL(:,col) <= Max);
                            app.FLAGS(Mir,index,app.kMid)=1;

                            app.PlotPseudosection;
                            app.PlotDataProfile;
                            app.Shown;
                        else

                            app.FindSelectedGates;

                            if app.Graph=='patc'
                                if app.Type=='data'
                                    MPOL=app.MPOL;
                                elseif app.Type=='forw'
                                    MPOL=app.fMPOL;
                                elseif app.Type=='misf'
                                    MPOL=app.mMPOL;
                                end
                            else
                                MPOL=app.MPOL;
                            end

                            for k=2:app.kMid
                                app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                            end
                            for i=1:height(app.rowFLAGS)
                                Max=max(MPOL(app.rowFLAGS(i),23+2*app.NGates+app.colFLAGS{app.rowFLAGS(i),1}));
                                Mir=find(MPOL(app.rowFLAGS(i),26+2*app.NGates:24+3*app.NGates) <= Max);
                                app.FLAGS(app.rowFLAGS(i),Mir+2,app.kMid)=0;
                            end

                            app.PlotDecay;
                            if ~isempty(app.Tree.SelectedNodes)
                                app.PlotDataStripe;
                                app.PlotPatchwork;
                            end
                        end
                end

            elseif isequal(char(event.Modifier),'shift')
                switch key
                    case 'q'            % SHIFT+Q: remove all the gates from the decays
                        % in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                app.FLAGS(app.rowFLAGS(i),3:end,app.kMid)=1;
                            end
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'a'            % SHIFT+A: restore all the gates from the decays
                        % in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                app.FLAGS(app.rowFLAGS(i),3:end,app.kMid)=0;
                            end
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end
                        %%

                    case 'n'            % SHIFT+N: remove all the negative gates from
                        % the decays in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            for j=1:app.NGates-1
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    if app.MPOL(app.rowFLAGS(i),25+app.NGates*2+j)<0
                                        app.FLAGS(app.rowFLAGS(i),j+2,app.kMid)=1;
                                    end
                                end
                            end
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'm'        % SHIFT+M: restore all the positive gates from
                        % the decays in which there is a selection

                        app.FindSelectedGates;

                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for i=1:height(app.rowFLAGS)
                            for j=1:app.NGates-1
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    if app.MPOL(app.rowFLAGS(i),25+app.NGates*2+j)>0
                                        app.FLAGS(app.rowFLAGS(i),j+2,app.kMid)=0;
                                    end
                                end
                            end
                        end

                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                end

            elseif isequal(char(event.Modifier),'control')
                switch key

                    case 'z'            % CTRL+Z: undo

                        for k=app.kEnd:-1:2
                            app.FLAGS(:,:,k)=app.FLAGS(:,:,k-1);
                        end

                        app.PlotPseudosection;
                        app.PlotDataProfile;
                        app.Shown;
                        app.PlotDecay;

                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'y'            % CTRL+Y: redo

                        if app.FLAGS(1,1,app.kMid+1)==0 || app.FLAGS(1,1,app.kMid+1)==1
                            for k=1:app.kEnd-1
                                app.FLAGS(:,:,k)=app.FLAGS(:,:,k+1);
                            end
                        end

                        app.PlotPseudosection;
                        app.PlotDataProfile;
                        app.Shown;
                        app.PlotDecay;

                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end


                    case 'q'            % CTRL+Q: remove all the gates selected
                        % from all the decays of the set
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    app.FLAGS(app.SetPdepthSide{m,n}(j,1),app.colFLAGS{app.rowFLAGS(i)},app.kMid)=1;
                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'a'            % CTRL+A: restore all the gates selected
                        % from all the decays of the set
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    app.FLAGS(app.SetPdepthSide{m,n}(j,1),app.colFLAGS{app.rowFLAGS(i)},app.kMid)=0;
                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'w'            % CTRL+W: remove all the gates from the first gate to the gate selected
                        % from all the decays of the set
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    app.FLAGS(app.SetPdepthSide{m,n}(j,1),3:app.colFLAGS{app.rowFLAGS(i)},app.kMid)=1;
                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 's'            % CTRL+S: restore all the gates from the first gate to the gate selected
                        % from all the decays of the set
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    app.FLAGS(app.SetPdepthSide{m,n}(j,1),3:app.colFLAGS{app.rowFLAGS(i)},app.kMid)=0;
                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'e'            % CTRL+W: remove all the gates from the first gate to the gate selected
                        % from all the decays of the set
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    app.FLAGS(app.SetPdepthSide{m,n}(j,1),app.colFLAGS{app.rowFLAGS(i)}:end,app.kMid)=1;
                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'd'            % CTRL+S: restore all the gates from the first gate to the gate selected
                        % from all the decays of the set
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                    app.FLAGS(app.SetPdepthSide{m,n}(j,1),app.colFLAGS{app.rowFLAGS(i)}:end,app.kMid)=0;
                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'n'            % CTRL+N: remove all the gates selected
                        % from all the decays of the set, if negative
                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                for l=1:length(app.colFLAGS{app.rowFLAGS(i)})

                                    if app.MPOL(app.SetPdepthSide{m,n}(j,1),23+app.NGates*2+app.colFLAGS{app.rowFLAGS(i)}(l))<0
                                        app.FLAGS(app.SetPdepthSide{m,n}(j,1),app.colFLAGS{app.rowFLAGS(i)}(l),app.kMid)=1;
                                    end

                                end
                            end
                        end


                        app.PlotDecay;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                    case 'm'            % CTRL+M: restore all the gates selected
                        % from all the decays of the set, if positive

                        app.FindSelectedGates;

                        selectedNodes = app.Tree.SelectedNodes;

                        [m,n] = find(app.NameSetPdepthSide==selectedNodes.Text);
                        hSet=height(app.SetPdepthSide{m,n});


                        for k=2:app.kMid
                            app.FLAGS(:,:,k-1)=app.FLAGS(:,:,k);
                        end
                        for j=1:hSet
                            for i=1:height(app.rowFLAGS)
                                for l=1:length(app.colFLAGS{app.rowFLAGS(i)})
                                    if ~isempty(app.colFLAGS{app.rowFLAGS(i)})
                                        if app.MPOL(app.SetPdepthSide{m,n}(j,1),23+app.NGates*2+app.colFLAGS{app.rowFLAGS(i)}(l))>0
                                            app.FLAGS(app.SetPdepthSide{m,n}(j,1),app.colFLAGS{app.rowFLAGS(i)}(l),app.kMid)=0;
                                        end
                                    end
                                end
                            end
                        end

                        app.PlotDecay;
                        app.Shown;
                        if ~isempty(app.Tree.SelectedNodes)
                            app.PlotDataStripe;
                            app.PlotPatchwork;
                        end

                end

            elseif isequal(key,'space') % Show selected measurements in Decays and
                % delete current brush selection

                if width(app.MPOL)>25
                    cla(app.UIAxes4)
                    app.FindSelectedRow;
                    app.Shown;
                    app.PlotDecay;
                end

                for l=1:length(app.UIAxes.Children)
                    app.UIAxes.Children(l).BrushData=0;
                end
                for l=1:length(app.UIAxes2.Children)
                    app.UIAxes2.Children(l).BrushData=0;
                end
                for l2=1:length(app.UIAxes3.Children)
                    app.UIAxes3.Children(l2).BrushData=0;
                end
                for l2=1:length(app.UIAxes4.Children)
                    if app.UIAxes4.Children(l2).Marker=='none'
                        app.UIAxes4.Children(l2).BrushData=0;
                    end
                end
                for l2=1:length(app.UIAxes3_2.Children)
                    if app.UIAxes3_2.Children(l2).Marker~='.'
                        app.UIAxes3_2.Children(l2).BrushData=0;
                    end
                end
                for l2=1:length(app.UIAxes3_3.Children)
                    try
                        app.UIAxes3_3.Children(l2).BrushData=0;
                    end
                end
            end
        end

        % Button pushed function: SaveprocessingButton
        function SaveprocessingButtonPushed(app, event)
            %%Substitute flags in MPOL with the processed flags
            app.MPOL(:,19+app.NGates:19+app.NGates*2)=app.FLAGS(:,:,10);

            %%Reassembling .qui file
            FileID=fopen(app.QuiFile);
            filescan=textscan(FileID,'%s','delimiter','\n','whitespace',' ');

            % Read %0
            ROW=zeros(1,2);
            i=0;
            while i<=1
                row_i=strfind(filescan{1}, append('%',num2str(i)));
                row_i=find(~cellfun('isempty',row_i),1);

                ROW(1,i+1)=row_i;
                i=i+1;
            end          % ROW (vector) contains the number of the row of each section

            TAB0=filescan{1}(ROW(1,1)+1:ROW(1,2)-2);


            %Pathname=uigetdir;
            %FID=fopen(append(Pathname,'\',QuiFile),'w+');
            FID=fopen(app.QuiFile,'w+');

            %0
            Header0=' %0      Comments';
            fprintf(FID,'%s\n',Header0);
            toPrint=append('                     ',string(TAB0));
            fprintf(FID,'%s\n',toPrint);
            fprintf(FID,'\n');

            %1
            Header1=           ' %1      VersionNumber  UTMzone      Date  Location  Operator  Temperature  RawDataFolder';
            fprintf(FID,'%s\n',Header1);
            toPrint=append('                     ',string(app.TAB{1}));
            fprintf(FID,'%s\n\n',toPrint);


            %2
            Header2=' %2 NElectrodeScalings  NElectrodes  NLines  NFilters  NGates  NStackSets  NCurrWaves NGateSets  NVES  NHES  NMultipoles';

            fprintf(FID,'%s\n',Header2);
            fprintf(FID,'%22i %12i %7i %9i %7i %11i %11i %9i %5i %5i %12i\n',app.TAB{2});
            fprintf(FID,'\n');


            %3
            Header3=' %3 ScalingID  XScaling  YScaling  ZScaling';

            fprintf(FID,'%s\n',Header3);
            fprintf(FID,'%13i %9i %9i %9i\n',app.TAB{3});
            fprintf(FID,'\n');


            %4
            Header4=' %4 ElectrodeID ScalingID         UTMx         UTMy     Elevation         Depth ElFlag';
            Format4='   %12i %9i %12.4f %12.4f %13.4f %13.4f %6i\n';

            fprintf(FID,'%s\n',Header4');
            fprintf(FID,Format4,app.TAB{4}');
            fprintf(FID,'\n');

            %5
            Header5= ' %5   LineID NElectrodes   ElIDs  LineFlag';

            fprintf(FID,'%s\n',Header5);
            toPrint=append('           ',string(app.TAB{5}));
            fprintf(FID,'%s\n\n',toPrint);


            %6
            Header6=' %6 FilterID NLowPassFreqs NHighPassFreqs';

            fprintf(FID,'%s\n',Header6);
            toPrint=append('           ',string(app.TAB{6}));
            fprintf(FID,'%s\n\n',toPrint);


            %7
            Header7=' %7  GateID GateWidth   GateTime   GateType  GateFlag';
            Format7='   %8i %10.3e %10.3e %10i %9i\n';

            fprintf(FID,'%s\n',Header7');
            fprintf(FID,Format7,app.TAB{7}');
            fprintf(FID,'\n');

            %8
            Header8=' %8 StackID StackSize         t1      a1      t2      a2      t3      a3      t4      a4'; % DA AGGIORNARE CON WAVETYPE
            fprintf(FID,'%s\n',Header8);
            toPrint=append('          ',string(app.TAB{8}));
            fprintf(FID,'%s\n',toPrint);
            fprintf(FID,'\n');


            %9
            Header9=' %9 CurrWaveID NCurrValues WaveType NWavePoints   t1   a1';
            fprintf(FID,'%s\n',Header9);
            toPrint=append('             ',string(app.TAB{9}));
            fprintf(FID,'%s\n\n',toPrint);


            %10
            Header10=' %10 GateSetID DCGateID DCStackID TransType NDecays Decay01StackID Decay01FilterID Decay01NGates Decay01GateIDs';

            fprintf(FID,'%s\n',Header10);
            toPrint=string(app.TAB{10});
            fprintf(FID,'%14s %8s %9s %9s %7s %14s %15s %13s %14s ',toPrint);
            fprintf(FID,'\n\n');


            %11
            if width(app.MPOL)>25
                Header11=' %11 GateSetID NCurrWaves CW01ID CW01NTx  CW01Tx01     CW01A01  CW01Tx02     CW01A02 NRx     Rx1     Rx2     Xfocus     Yfocus     Pdepth VESID HESID CW01STD01    DCSTD  IntMSTD ';
                for i=1:app.NGates-1
                    Header11=[Header11,'  MSTD',num2str(i,'%2.2i')];
                end
                Header11=[Header11,'  DCFlag  IntMFlag'];
                for i=1:app.NGates-1
                    Header11=[Header11,'  MFlag',num2str(i,'%2.2i')];
                end
                Header11=[Header11,'       Time        Key        Rho  CW01Val01         DC       IntM'];
                for i=1:app.NGates-1
                    Header11=[Header11,'        M',num2str(i,'%2.2i')];
                end
                Header11=[Header11,'  RawFile'];

                Format11=' %13i %10i %6i %7i %9i %11.4e %9i %11.4e %3i %7i %7i %10.3e %10.3e %10.3e %5i %5i %9.2f %8.2f %8.2f ';

                for i=1:app.NGates-1
                    Format11=[Format11,' %7.2f'];
                end
                Format11=[Format11,' %7i %9i'];
                for i=1:app.NGates-1
                    Format11=[Format11,' %8i'];
                end
                for i=1:app.NGates+5
                    Format11=[Format11,' %10.3e'];
                end
                Format11=[Format11,'   %8i'];

            else
                Header11=' %11 GateSetID NCurrWaves CW01ID CW01NTx  CW01Tx01     CW01A01  CW01Tx02     CW01A02 NRx      Rx1      Rx2      Xfocus      Yfocus      Pdepth VESID HESID   CW01STD01       DCSTD  DCFlag          Time         Key         Rho   CW01Val01          DC    RawFile ';


                Format11=' %13i %10i %6i %7i %9i %11.4e %9i %11.4e %3i %8i %8i %11.3e %11.3e %11.3e %5i %5i %11.2f %11.2f %7i %13.3e';
                for i=1:4
                    Format11=[Format11,' %11.3e'];
                end
                Format11=[Format11,'   %8i'];

            end

            fprintf(FID,'%s\n',Header11');
            for i=1:height(app.MPOL)
                fprintf(FID,Format11,app.MPOL(i,:)');
                fprintf(FID,'  %s\n',string(app.RAWDATA(i))');
            end
            fclose(FID);

            saved=uiconfirm(app.UIFigure,'Saved', 'Saving processing','Icon','success','Options',{'OK'});


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                try
                    Q=cell(1,1);
                    importdata_processingapp(app.callingapp,Q,app.TAB);
                end
                delete(app.settings_profileapp)
                delete(app.settings_pseudosecapp)
                delete(app.settings_stripeapp)
                delete(app.settings_decayapp)
                delete(app.settings_positionsapp)
                delete(app.settings_patchworkapp)
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Visible = 'off';
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ListofshortcutsMenu = uimenu(app.HelpMenu);
            app.ListofshortcutsMenu.MenuSelectedFcn = createCallbackFcn(app, @ListofshortcutsMenuSelected, true);
            app.ListofshortcutsMenu.Text = 'List of shortcuts';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.Tree = uitree(app.ProfileTab, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ShowallButton = uibutton(app.ProfileTab, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonPushed, true);
            app.ShowallButton.Text = 'Show all';
            app.HideallButton = uibutton(app.ProfileTab, 'push');
            app.HideallButton.ButtonPushedFcn = createCallbackFcn(app, @HideallButtonPushed, true);
            app.HideallButton.Text = 'Hide all';
            app.PositionPanel = uipanel(app.UIFigure);
            app.PositionPanel.Title = 'Position';
            app.UIAxes = uiaxes(app.PositionPanel);
            xlabel(app.UIAxes, 'Distance [m]')
            ylabel(app.UIAxes, 'Elevation [m]')
            zlabel(app.UIAxes, 'Z')
            app.SettingsButton_positions = uibutton(app.PositionPanel, 'push');
            app.SettingsButton_positions.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_positionsPushed, true);
            app.SettingsButton_positions.Icon = 'Settings_2.png';
            app.SettingsButton_positions.Text = '';
            app.DecaysPanel = uipanel(app.UIFigure);
            app.DecaysPanel.Title = 'Decays';
            app.UIAxes4 = uiaxes(app.DecaysPanel);
            xlabel(app.UIAxes4, 't [s]')
            ylabel(app.UIAxes4, 'M [mV/V]')
            zlabel(app.UIAxes4, 'Z')
            app.SettingsButton_decay = uibutton(app.DecaysPanel, 'push');
            app.SettingsButton_decay.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_decayPushed, true);
            app.SettingsButton_decay.Icon = 'Settings_2.png';
            app.SettingsButton_decay.Text = '';
            app.RawdataButton = uibutton(app.DecaysPanel, 'push');
            app.RawdataButton.ButtonPushedFcn = createCallbackFcn(app, @RawdataButtonPushed, true);
            app.RawdataButton.Visible = 'off';
            app.RawdataButton.Text = 'Raw data';
            app.TypeDropDown_2Label = uilabel(app.DecaysPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeDropDown_decays = uidropdown(app.DecaysPanel);
            app.TypeDropDown_decays.Items = {'M (data)'};
            app.TypeDropDown_decays.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_decaysValueChanged, true);
            app.TypeDropDown_decays.Value = 'M (data)';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.DataprofileandPseudosectionTab = uitab(app.TabGroup2);
            app.DataprofileandPseudosectionTab.Title = 'Data profile and Pseudosection';
            app.Panel = uipanel(app.DataprofileandPseudosectionTab);
            app.UIAxes3 = uiaxes(app.Panel);
            xlabel(app.UIAxes3, 'Distance [m]')
            ylabel(app.UIAxes3, 'Elevation [m]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YDir = 'reverse';
            app.TypeDropDownLabel_2 = uilabel(app.Panel);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.TypeDropDown_pseudosection = uidropdown(app.Panel);
            app.TypeDropDown_pseudosection.Items = {'Rhoa (data)', 'intM (data)'};
            app.TypeDropDown_pseudosection.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_pseudosectionValueChanged, true);
            app.TypeDropDown_pseudosection.Value = 'Rhoa (data)';
            app.ColorbarEditorButton = uibutton(app.Panel, 'push');
            app.ColorbarEditorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorbarEditorButtonPushed, true);
            app.ColorbarEditorButton.Text = 'Colorbar Editor';
            app.SettingsButton_pseudosec = uibutton(app.Panel, 'push');
            app.SettingsButton_pseudosec.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_pseudosecPushed, true);
            app.SettingsButton_pseudosec.Icon = 'Settings_2.png';
            app.SettingsButton_pseudosec.Text = '';
            app.Panel_2 = uipanel(app.DataprofileandPseudosectionTab);
            app.UIAxes2 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes2, 'Distance [m]')
            ylabel(app.UIAxes2, 'Rhoa [ohm m]')
            zlabel(app.UIAxes2, 'Z')
            app.CurveshiftfactorSpinnerLabel = uilabel(app.Panel_2);
            app.CurveshiftfactorSpinnerLabel.Text = 'Curve shift factor';
            app.CurveshiftfactorSpinner = uispinner(app.Panel_2);
            app.CurveshiftfactorSpinner.Step = 0.1;
            app.CurveshiftfactorSpinner.Limits = [1 2];
            app.CurveshiftfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @CurveshiftfactorSpinnerValueChanged, true);
            app.CurveshiftfactorSpinner.Editable = 'off';
            app.CurveshiftfactorSpinner.Value = 1;
            app.SettingsButton_profile = uibutton(app.Panel_2, 'push');
            app.SettingsButton_profile.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_profilePushed, true);
            app.SettingsButton_profile.Icon = 'Settings_2.png';
            app.SettingsButton_profile.Text = '';
            app.TypeDropDownLabel = uilabel(app.Panel_2);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown_profile = uidropdown(app.Panel_2);
            app.TypeDropDown_profile.Items = {'Rhoa (data)', 'intM (data)'};
            app.TypeDropDown_profile.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_profileValueChanged, true);
            app.TypeDropDown_profile.Value = 'Rhoa (data)';
            app.DatastripeTab = uitab(app.TabGroup2);
            app.DatastripeTab.Title = 'Data stripe';
            app.Panel_4 = uipanel(app.DatastripeTab);
            app.Previous = uibutton(app.Panel_4, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousPushed, true);
            app.Previous.Enable = 'off';
            app.Previous.Text = '<';
            app.Next = uibutton(app.Panel_4, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextPushed, true);
            app.Next.Enable = 'off';
            app.Next.Text = '>';
            app.SettingsButton_stripe = uibutton(app.Panel_4, 'push');
            app.SettingsButton_stripe.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_stripePushed, true);
            app.SettingsButton_stripe.Icon = 'Settings_2.png';
            app.SettingsButton_stripe.Text = '';
            app.TypeDropDown_3Label = uilabel(app.Panel_4);
            app.TypeDropDown_3Label.Text = 'Type';
            app.TypeDropDown_stripe = uidropdown(app.Panel_4);
            app.TypeDropDown_stripe.Items = {'M (data)'};
            app.TypeDropDown_stripe.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_stripeValueChanged, true);
            app.TypeDropDown_stripe.Value = 'M (data)';
            app.HideerrorbarsCheckBox = uicheckbox(app.Panel_4);
            app.HideerrorbarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideerrorbarsCheckBoxValueChanged, true);
            app.HideerrorbarsCheckBox.Text = 'Hide error bars';
            app.Panel_5 = uipanel(app.DatastripeTab);
            app.UIAxes3_2 = uiaxes(app.Panel_5);
            xlabel(app.UIAxes3_2, 'Distance [m]')
            ylabel(app.UIAxes3_2, 'M [mV/V]')
            zlabel(app.UIAxes3_2, 'Z')
            app.PatchworkTab = uitab(app.TabGroup2);
            app.PatchworkTab.Title = 'Patchwork';
            app.Panel_7 = uipanel(app.PatchworkTab);
            app.SettingsButton_patchwork = uibutton(app.Panel_7, 'push');
            app.SettingsButton_patchwork.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_patchworkPushed, true);
            app.SettingsButton_patchwork.Icon = 'Settings_2.png';
            app.SettingsButton_patchwork.Text = '';
            app.TypeDropDown_3Label_2 = uilabel(app.Panel_7);
            app.TypeDropDown_3Label_2.Text = 'Type';
            app.TypeDropDown_patchwork = uidropdown(app.Panel_7);
            app.TypeDropDown_patchwork.Items = {'M (data)'};
            app.TypeDropDown_patchwork.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_patchworkValueChanged, true);
            app.TypeDropDown_patchwork.Value = 'M (data)';
            app.ColorbarEditorButton_2 = uibutton(app.Panel_7, 'push');
            app.ColorbarEditorButton_2.ButtonPushedFcn = createCallbackFcn(app, @ColorbarEditorButton_2Pushed, true);
            app.ColorbarEditorButton_2.Text = 'Colorbar Editor';
            app.Panel_8 = uipanel(app.PatchworkTab);
            app.UIAxes3_3 = uiaxes(app.Panel_8);
            xlabel(app.UIAxes3_3, 'Distance [m]')
            ylabel(app.UIAxes3_3, 'Gates')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_3.YDir = 'reverse';
            app.UIAxes3_3.XTick = [];
            app.Panel_6 = uipanel(app.UIFigure);
            app.SaveprocessingButton = uibutton(app.Panel_6, 'push');
            app.SaveprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprocessingButtonPushed, true);
            app.SaveprocessingButton.Icon = 'SaveProcessing.png';
            app.SaveprocessingButton.Text = 'Save processing ';
            app.HiderejectedpointsCheckBox = uicheckbox(app.Panel_6);
            app.HiderejectedpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HiderejectedpointsCheckBoxValueChanged, true);
            app.HiderejectedpointsCheckBox.Text = 'Hide rejected points';
            app.UpdateintMvaluesButton = uibutton(app.Panel_6, 'push');
            app.UpdateintMvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateintMvaluesButtonPushed, true);
            app.UpdateintMvaluesButton.Text = {'Update intM values'; ''};
            app.AddforwardfileButton = uibutton(app.UIFigure, 'push');
            app.AddforwardfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddforwardfileButtonPushed, true);
            app.AddforwardfileButton.Text = 'Add forward file';
            app.ForwardEditField = uieditfield(app.UIFigure, 'text');
            app.ForwardEditField.ValueChangedFcn = createCallbackFcn(app, @ForwardEditFieldValueChanged, true);
            app.ForwardEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processingapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end